classdef winConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        DockModule                      matlab.ui.container.GridLayout
        dockModule_Undock               matlab.ui.control.Image
        dockModule_Close                matlab.ui.control.Image
        SubTabGroup                     matlab.ui.container.TabGroup
        SubTab1                         matlab.ui.container.Tab
        SubGrid1                        matlab.ui.container.GridLayout
        openAuxiliarApp2Debug           matlab.ui.control.CheckBox
        openAuxiliarAppAsDocked         matlab.ui.control.CheckBox
        versionInfo                     matlab.ui.control.Label
        tool_versionInfoRefresh         matlab.ui.control.Image
        versionInfoLabel                matlab.ui.control.Label
        SubTab2                         matlab.ui.container.Tab
        SubGrid2                        matlab.ui.container.GridLayout
        config_SearchModeLabel          matlab.ui.control.Label
        config_SearchModePanel          matlab.ui.container.ButtonGroup
        config_SearchModeListOfWords    matlab.ui.control.RadioButton
        config_SearchModeTokenSuggestion  matlab.ui.control.RadioButton
        config_SearchModeDefaultParameters  matlab.ui.control.Image
        config_SelectedTableColumns     matlab.ui.container.CheckBoxTree
        config_SelectedTableColumnsLabel  matlab.ui.control.Label
        config_MiscelaneousPanel2       matlab.ui.container.Panel
        config_MiscelaneousGrid2        matlab.ui.container.GridLayout
        config_WordCloudColumn          matlab.ui.control.DropDown
        config_WordCloudColumnLabel     matlab.ui.control.Label
        config_WordCloudAlgorithm       matlab.ui.control.DropDown
        config_WordCloudAlgorithmLabel  matlab.ui.control.Label
        config_MiscelaneousLabel2       matlab.ui.control.Label
        config_MiscelaneousPanel1       matlab.ui.container.Panel
        config_MiscelaneousGrid1        matlab.ui.container.GridLayout
        config_nMinWords                matlab.ui.control.DropDown
        config_nMinWordsLabel           matlab.ui.control.Label
        config_nMinCharacters           matlab.ui.control.Spinner
        config_nMinCharactersLabel      matlab.ui.control.Label
        config_MiscelaneousLabel1       matlab.ui.control.Label
        SubTab3                         matlab.ui.container.Tab
        SubGrid3                        matlab.ui.container.GridLayout
        reportPanel                     matlab.ui.container.Panel
        reportGrid                      matlab.ui.container.GridLayout
        reportDocType                   matlab.ui.control.DropDown
        reportDocTypeLabel              matlab.ui.control.Label
        reportLabel                     matlab.ui.control.Label
        eFiscalizaPanel                 matlab.ui.container.Panel
        eFiscalizaGrid                  matlab.ui.container.GridLayout
        reportUnit                      matlab.ui.control.DropDown
        reportUnitLabel                 matlab.ui.control.Label
        reportSystem                    matlab.ui.control.DropDown
        reportSystemLabel               matlab.ui.control.Label
        eFiscalizaRefresh               matlab.ui.control.Image
        eFiscalizaLabel                 matlab.ui.control.Label
        SubTab4                         matlab.ui.container.Tab
        SubGrid4                        matlab.ui.container.GridLayout
        userPathButton                  matlab.ui.control.Image
        userPath                        matlab.ui.control.EditField
        userPathLabel                   matlab.ui.control.Label
        DataHubPOSTButton               matlab.ui.control.Image
        DataHubPOST                     matlab.ui.control.EditField
        DATAHUBPOSTLabel                matlab.ui.control.Label
        DataHubGETButton                matlab.ui.control.Image
        DataHubGET                      matlab.ui.control.EditField
        DATAHUBGETLabel                 matlab.ui.control.Label
        Toolbar                         matlab.ui.container.GridLayout
        exportTable                     matlab.ui.control.Image
        openDevTools                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryApp'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        mainApp
        jsBackDoor
        progressDialog
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        defaultValues
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        function ipcSecondaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        appEngine.activate(app, app.Role)
                        
                    otherwise
                        error('UnexpectedEvent')
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function applyJSCustomizations(app, tabIndex)
            persistent customizationStatus
            if isempty(customizationStatus)
                customizationStatus = [false, false, false, false];
            end

            switch tabIndex
                case 0 % STARTUP
                    if app.isDocked
                        app.progressDialog = app.mainApp.progressDialog;
                    else
                        sendEventToHTMLSource(app.jsBackDoor, 'startup', app.mainApp.executionMode);
                        app.progressDialog = ui.ProgressDialog(app.jsBackDoor);
                    end
                    customizationStatus = [false, false, false, false];

                otherwise
                    if customizationStatus(tabIndex)
                        return
                    end

                    customizationStatus(tabIndex) = true;
                    switch tabIndex
                        case 1
                            appName = class(app);

                            % Grid botões "dock":
                            if app.isDocked
                                elToModify = {app.DockModule};
                                elDataTag  = ui.CustomizationBase.getElementsDataTag(elToModify);
                                if ~isempty(elDataTag)
                                    sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                                        struct('appName', appName, 'dataTag', elDataTag{1}, 'style', struct('transition', 'opacity 2s ease', 'opacity', '0.5')), ...
                                    });
                                end
                            end
                            
                            % Outros elementos:
                            elToModify = {app.versionInfo};
                            elDataTag  = ui.CustomizationBase.getElementsDataTag(elToModify);
                            if ~isempty(elDataTag)
                                ui.TextView.startup(app.jsBackDoor, app.versionInfo, appName);
                            end

                        case 2
                            if ~strcmp(app.mainApp.executionMode, 'webApp')
                                app.config_WordCloudAlgorithm.Enable = 1;
                            end
                            updatePanel_Analysis(app)

                        case 3
                            if ~isdeployed()
                                app.reportSystem.Items = {'eFiscaliza', 'eFiscaliza TS', 'eFiscaliza HM', 'eFiscaliza DS'};
                            end
                            updatePanel_Report(app)

                        case 4
                            if ~strcmp(app.mainApp.executionMode, 'webApp')
                                set([app.DataHubGETButton, app.DataHubPOSTButton, app.userPathButton], 'Enable', 1)
                            end
                            updatePanel_Folder(app)
                    end
            end
        end

        %-----------------------------------------------------------------%
        function initializeAppProperties(app)
            % Lê a versão de "GeneralSettings.json" que vem junto ao
            % projeto (e não a versão armazenada em "ProgramData").
            projectFolder     = appEngine.util.Path(class.Constants.appName, app.mainApp.rootFolder);
            projectFilePath   = fullfile(projectFolder, 'GeneralSettings.json');
            projectGeneral    = jsondecode(fileread(projectFilePath));

            app.defaultValues = struct('Search',   struct('search', projectGeneral.search, 'ui', projectGeneral.ui), ...
                                       'Products', projectGeneral.products, ...
                                       'Report',   projectGeneral.Report);
            app.defaultValues.Search.ui.searchTable = struct2table(app.defaultValues.Search.ui.searchTable);
        end

        %-----------------------------------------------------------------%
        function initializeUIComponents(app)
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.dockModule_Undock.Enable       = 1;
                app.openDevTools.Enable            = 1;
                app.tool_versionInfoRefresh.Enable = 1;
                app.openAuxiliarAppAsDocked.Enable = 1;
            end

            if ~isdeployed
                app.openAuxiliarApp2Debug.Enable   = 1;
            end

            if isfolder(app.mainApp.General.fileFolder.DataHub_GET)
                app.exportTable.Enable = 'on';
            end
        end

        %-----------------------------------------------------------------%
        function applyInitialLayout(app)
            % Versão:
            appInfo = util.HtmlTextGenerator.AppInfo( ...
                app.mainApp.General, ...
                app.mainApp.rootFolder, ...
                app.mainApp.executionMode, ...
                app.mainApp.renderCount, ...
                app.mainApp.rawDataTable, ...
                app.mainApp.releasedData, ...
                app.mainApp.cacheData, ...
                app.mainApp.annotationTable, ...
                'textview' ...
            );
            ui.TextView.update(app.versionInfo, appInfo);

            % Modo de operação:
            app.openAuxiliarAppAsDocked.Value = app.mainApp.General.operationMode.Dock;
            app.openAuxiliarApp2Debug.Value   = app.mainApp.General.operationMode.Debug;
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function updatePanel_Analysis(app)
            % MODO
            switch app.mainApp.General.search.mode
                case 'tokens'
                    app.config_SearchModeTokenSuggestion.Value = 1;
                otherwise
                    app.config_SearchModeListOfWords.Value     = 1;
            end

            % ALGORITMO SUGESTÃO DE TOKENS
            app.config_nMinCharacters.Value     = app.mainApp.General.search.minCharacters;

            numDisplayedTokens = num2str(app.mainApp.General.search.minDisplayedTokens);
            if ismember(numDisplayedTokens, app.config_nMinWords.Items)
                app.config_nMinWords.Value      = numDisplayedTokens;
            end

            % ANOTAÇÃO DO TIPO "WORDCLOUD"
            app.config_WordCloudAlgorithm.Value = app.mainApp.General.search.wordCloud.algorithm;
            app.config_WordCloudColumn.Value    = app.mainApp.General.search.wordCloud.column;

            % VISIBILIDADE DE COLUNAS
            columnCheckedList = matlab.ui.container.TreeNode.empty;
            columnStaticList  = matlab.ui.container.TreeNode.empty;

            if ~isempty(app.config_SelectedTableColumns.Children)
                delete(app.config_SelectedTableColumns.Children)
            end

            for ii = 1:height(app.mainApp.General.ui.searchTable)
                columnName     = app.mainApp.General.ui.searchTable.name{ii};
                columnVisible  = app.mainApp.General.ui.searchTable.visible(ii);
                columnPosition = app.mainApp.General.ui.searchTable.columnPosition(ii);

                treeNode       = uitreenode(app.config_SelectedTableColumns, 'Text', columnName);
                if columnPosition % static
                    columnStaticList(end+1)  = treeNode;
                end

                if columnVisible % visible
                    columnCheckedList(end+1) = treeNode;
                end
            end

            app.config_SelectedTableColumns.CheckedNodes = columnCheckedList;
            addStyle(app.config_SelectedTableColumns, class.Constants.configStyle5, 'node', columnStaticList)

            if checkEdition(app, 'SEARCH')
                app.config_SearchModeDefaultParameters.Visible = 1;
            else
                app.config_SearchModeDefaultParameters.Visible = 0;
            end
        end

        %-----------------------------------------------------------------%
        function updatePanel_Report(app)
            app.reportSystem.Value  = app.mainApp.General.Report.system;
            set(app.reportUnit, 'Items', app.mainApp.General.eFiscaliza.defaultValues.unit, 'Value', app.mainApp.General.Report.unit)
            app.reportDocType.Value = app.mainApp.General.Report.Document;

            if checkEdition(app, 'REPORT')
                app.eFiscalizaRefresh.Visible = 1;
            else
                app.eFiscalizaRefresh.Visible = 0;
            end
        end

        %-----------------------------------------------------------------%
        function updatePanel_Folder(app)
            DataHub_GET  = app.mainApp.General.fileFolder.DataHub_GET;
            if isfolder(DataHub_GET)
                app.DataHubGET.Value = DataHub_GET;
            end

            DataHub_POST = app.mainApp.General.fileFolder.DataHub_POST;    
            if isfolder(DataHub_POST)
                app.DataHubPOST.Value = DataHub_POST;
            end

            app.userPath.Value = app.mainApp.General.fileFolder.userPath;                
        end

        %-----------------------------------------------------------------%
        function columnInfo = search_Table_ColumnInfo(app, type)
            switch type
                case 'staticColumns'
                    staticLogical  = logical(app.mainApp.General.ui.searchTable.columnPosition);
                    staticIndex    = app.mainApp.General.ui.searchTable.columnPosition(staticLogical);
                    [~, idxOrder]  = sort(staticIndex);
                    columnList     = app.mainApp.General.ui.searchTable.name(staticLogical);
                    columnInfo     = columnList(idxOrder);

                case 'visibleColumns'
                    visibleLogical = logical(app.mainApp.General.ui.searchTable.visible);
                    columnInfo     = app.mainApp.General.ui.searchTable.name(visibleLogical);

                case 'allColumns'
                    columnInfo     = app.mainApp.General.ui.searchTable.name;

                case 'allColumnsWidths'
                    columnInfo     = app.mainApp.General.ui.searchTable.columnWidth;
            end
        end

        %-----------------------------------------------------------------%
        function editionFlag = checkEdition(app, tabName)
            editionFlag   = false;
            currentValues = struct('Search',   struct('search', app.mainApp.General.search, 'ui', app.mainApp.General.ui), ...
                                   'Produtcs', app.mainApp.General.products, ...
                                   'Report',   app.mainApp.General.Report);

            switch tabName
                case 'SEARCH'
                    if ~isequal(currentValues.Search, app.defaultValues.Search)
                        editionFlag = true;
                    end
                case 'PRODUCTS'
                    if ~isequal(currentValues.Produtcs, app.defaultValues.Produtcs)
                        editionFlag = true;
                    end
                case 'REPORT'
                    if ~isequal(currentValues.Report, app.defaultValues.Report)
                        editionFlag = true;
                    end
            end
        end

        %-----------------------------------------------------------------%
        function saveGeneralSettings(app)
            appEngine.util.generalSettingsSave(class.Constants.appName, app.mainApp.rootFolder, app.mainApp.General_I, app.mainApp.executionMode)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            try
                appEngine.boot(app, app.Role, mainApp)
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn', "CONFIG")
            delete(app)
            
        end

        % Image clicked function: dockModule_Close, dockModule_Undock
        function DockModuleGroup_ButtonPushed(app, event)
            
            [idx, auxAppTag, relatedButton] = getAppInfoFromHandle(app.mainApp.tabGroupController, app);

            switch event.Source
                case app.dockModule_Undock
                    appGeneral = app.mainApp.General;
                    appGeneral.operationMode.Dock = false;
                    
                    app.mainApp.tabGroupController.Components.appHandle{idx} = [];

                    inputArguments = ipcMainMatlabCallsHandler(app.mainApp, app, 'dockButtonPushed', auxAppTag);
                    openModule(app.mainApp.tabGroupController, relatedButton, false, appGeneral, inputArguments{:})
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General, 'undock')
                    
                    delete(app)

                case app.dockModule_Close
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General)
            end

        end

        % Selection change function: SubTabGroup
        function SubTabGroup_TabSelectionChanged(app, event)
            
            [~, tabIndex] = ismember(app.SubTabGroup.SelectedTab, app.SubTabGroup.Children);
            applyJSCustomizations(app, tabIndex)
            
        end

        % Image clicked function: tool_versionInfoRefresh
        function Toolbar_AppEnvRefreshButtonPushed(app, event)
            
            app.progressDialog.Visible = 'visible';

            htmlContent = util.HtmlTextGenerator.checkUpdate(app.mainApp.General, app.mainApp.rootFolder);
            ui.Dialog(app.UIFigure, "info", htmlContent);       

            app.progressDialog.Visible = 'hidden';

        end

        % Image clicked function: exportTable, openDevTools
        function ToolbarButtonPushed(app, event)
            
            switch event.Source
                case app.exportTable
                    nameFormatMap = {'*.xlsx', 'Excel (*.xlsx)'};
                    defaultName   = appEngine.util.DefaultFileName(app.mainApp.General.fileFolder.userPath, 'SCH', -1);
                    fileFullPath  = ui.Dialog(app.UIFigure, 'uiputfile', '', nameFormatMap, defaultName);
                    if isempty(fileFullPath)
                        return
                    end
        
                    app.progressDialog.Visible = 'visible';
        
                    try
                        fileName = sprintf('SCHData%s.xlsx', app.mainApp.General.search.dataBaseVersion);
                        copyfile(fullfile(app.mainApp.General.fileFolder.DataHub_GET, fileName), fileFullPath, 'f')

                        if ~strcmp(app.mainApp.executionMode, 'webApp')
                            appEngine.util.OperationSystem('openFile', fileFullPath)
                        end
                    catch ME
                        ui.Dialog(app.UIFigure, 'warning', getReport(ME));
                    end
        
                    app.progressDialog.Visible = 'hidden';

                case app.openDevTools
                    ipcMainMatlabCallsHandler(app.mainApp, app, 'openDevTools')
            end

        end

        % Image clicked function: config_SearchModeDefaultParameters
        function Analysis_DefaultParametersClicked(app, event)
            
            % Lê a versão de "GeneralSettings.json" que vem junto ao
            % projeto (e não a versão armazenada em "ProgramData").
            projectFolder      = appEngine.util.Path(class.Constants.appName, app.mainApp.rootFolder);
            projectFilePath    = fullfile(projectFolder, 'GeneralSettings.json');

            projectFileContent = jsondecode(fileread(projectFilePath));
            projectFileContent.ui.searchTable = struct2table(projectFileContent.ui.searchTable);

            if isequal(app.mainApp.General.search, projectFileContent.search) && isequal(app.mainApp.General.ui, projectFileContent.ui)
                msgWarning = 'Configurações atuais já coincidem com as iniciais.';
                ui.Dialog(app.UIFigure, 'warning', msgWarning);
                return

            else
                ipcEventName = {};
                if ~isequal(app.mainApp.General.search.mode, projectFileContent.search.mode)
                    ipcEventName{end+1} = 'searchModeChanged';
                end

                if ~isequal(app.mainApp.General.search.wordCloud.algorithm, projectFileContent.search.wordCloud.algorithm)
                    ipcEventName{end+1} = 'wordCloudAlgorithmChanged';
                end

                if ~isequal(app.mainApp.General.ui.searchTable, projectFileContent.ui.searchTable)
                    ipcEventName{end+1} = 'searchVisibleColumnsChanged';
                end

                app.mainApp.General.search   = projectFileContent.search;
                app.mainApp.General.ui       = projectFileContent.ui;
                
                app.mainApp.General_I.search = app.mainApp.General.search;
                app.mainApp.General_I.ui     = app.mainApp.General.ui;                
                
                updatePanel_Analysis(app)
                saveGeneralSettings(app)

                for ii = 1:numel(ipcEventName)
                    ipcMainMatlabCallsHandler(app.mainApp, app, ipcEventName{ii})
                end
            end

        end

        % Callback function: config_SearchModePanel, 
        % ...and 5 other components
        function Analysis_ParameterValueChanged(app, event)
            
            ipcEventName = '';

            switch event.Source
                case app.config_SearchModePanel
                    ipcEventName = 'searchModeChanged';

                    switch app.config_SearchModePanel.SelectedObject
                        case app.config_SearchModeTokenSuggestion
                            app.mainApp.General.search.mode     = 'tokens';
                            app.mainApp.General.search.function = 'strcmp';
                        case app.config_SearchModeListOfWords
                            app.mainApp.General.search.mode     = 'words';
                            app.mainApp.General.search.function = 'contains';
                    end

                case app.config_nMinCharacters
                    app.mainApp.General.search.minCharacters = app.config_nMinCharacters.Value;

                case app.config_nMinWords
                    app.mainApp.General.search.minDisplayedTokens = str2double(app.config_nMinWords.Value);

                case app.config_WordCloudAlgorithm
                    ipcEventName = 'wordCloudAlgorithmChanged';
                    app.mainApp.General.search.wordCloud.algorithm = app.config_WordCloudAlgorithm.Value;

                case app.config_WordCloudColumn
                    app.mainApp.General.search.wordCloud.column = app.config_WordCloudColumn.Value;

                case app.config_SelectedTableColumns
                    ipcEventName = 'searchVisibleColumnsChanged';

                    % Inicialmente, certifica-se de que as colunas estáticas 
                    % se mantém selecionadas.
                    if ~isempty(app.config_SelectedTableColumns.CheckedNodes)
                        checkedColumns = {app.config_SelectedTableColumns.CheckedNodes.Text};
                    else
                        checkedColumns = {};
                    end
        
                    staticColumns = search_Table_ColumnInfo(app, 'staticColumns');
                    staticColumns(ismember(staticColumns, checkedColumns)) = [];
                    
                    if ~isempty(staticColumns)
                        for ii = 1:numel(staticColumns)
                            staticColumnName = staticColumns{ii};
                            staticTreeNodes  = findobj(app.config_SelectedTableColumns, 'Text', staticColumnName);
                            
                            app.config_SelectedTableColumns.CheckedNodes = [app.config_SelectedTableColumns.CheckedNodes; staticTreeNodes];
                        end
                    end

                    % E depois atualiza "GeneralSettings.json"...
                    finalCheckedColumns = {app.config_SelectedTableColumns.CheckedNodes.Text};
                    for jj = 1:height(app.mainApp.General.ui.searchTable)
                        app.mainApp.General.ui.searchTable.visible(jj) = ismember(app.mainApp.General.ui.searchTable.name{jj}, finalCheckedColumns);
                    end
            end

            app.mainApp.General_I.search = app.mainApp.General.search;
            app.mainApp.General_I.ui     = app.mainApp.General.ui;
            
            updatePanel_Analysis(app)
            saveGeneralSettings(app)

            if ~isempty(ipcEventName)
                ipcMainMatlabCallsHandler(app.mainApp, app, ipcEventName)
            end

        end

        % Value changed function: openAuxiliarApp2Debug, 
        % ...and 1 other component
        function Config_GeneralParameterValueChanged(app, event)
            
            switch event.Source
                case app.openAuxiliarAppAsDocked
                    app.mainApp.General.operationMode.Dock  = app.openAuxiliarAppAsDocked.Value;

                case app.openAuxiliarApp2Debug
                    app.mainApp.General.operationMode.Debug = app.openAuxiliarApp2Debug.Value;
            end

            app.mainApp.General_I.operationMode = app.mainApp.General.operationMode;
            saveGeneralSettings(app)
            
        end

        % Image clicked function: eFiscalizaRefresh
        function Config_ProjectRefreshImageClicked(app, event)
            
            if ~checkEdition(app, 'REPORT')
                app.eFiscalizaRefresh.Visible = 0;
                return
            
            else
                app.mainApp.General.Report   = app.defaultValues.Report;
                app.mainApp.General_I.Report = app.mainApp.General.Report;
                
                updatePanel_Report(app)
                saveGeneralSettings(app)
            end

        end

        % Value changed function: reportDocType, reportSystem, reportUnit
        function Config_ProjectParameterValueChanged(app, event)
            
            switch event.Source
                case app.reportSystem
                    app.mainApp.General.Report.system   = event.Value;

                case app.reportUnit
                    app.mainApp.General.Report.unit     = event.Value;

                case app.reportDocType
                    app.mainApp.General.Report.Document = event.Value;
            end

            app.mainApp.General_I.Report = app.mainApp.General.Report;

            updatePanel_Report(app)
            saveGeneralSettings(app)
            
        end

        % Image clicked function: DataHubGETButton, DataHubPOSTButton, 
        % ...and 1 other component
        function Config_FolderButtonPushed(app, event)
            
            try
                relatedFolder = eval(sprintf('app.%s.Value', event.Source.Tag));                    
            catch
                relatedFolder = app.mainApp.General.fileFolder.(event.Source.Tag);
            end
            
            if isfolder(relatedFolder)
                initialFolder = relatedFolder;
            elseif isfile(relatedFolder)
                initialFolder = fileparts(relatedFolder);
            else
                initialFolder = app.userPath.Value;
            end
            
            selectedFolder = uigetdir(initialFolder);
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                figure(app.UIFigure)
            end

            if selectedFolder
                switch event.Source
                    case app.DataHubGETButton
                        if strcmp(app.mainApp.General.fileFolder.DataHub_GET, selectedFolder) 
                            return
                        else
                            selectedFolderFiles = dir(selectedFolder);
                            if ~ismember('.sch_get', {selectedFolderFiles.name})
                                ui.Dialog(app.UIFigure, 'error', 'Não se trata da pasta "DataHub - GET", do SCH.');
                                return
                            end

                            app.DataHubGET.Value = selectedFolder;
                            app.mainApp.General.fileFolder.DataHub_GET = selectedFolder;
    
                            ipcMainMatlabCallsHandler(app.mainApp, app, 'updateDataHubGetFolder')
                            ipcMainMatlabCallsHandler(app.mainApp, app, 'checkDataHubLampStatus')
                        end

                    case app.DataHubPOSTButton
                        if strcmp(app.mainApp.General.fileFolder.DataHub_POST, selectedFolder) 
                            return
                        else
                            selectedFolderFiles = dir(selectedFolder);
                            if ~ismember('.sch_post', {selectedFolderFiles.name})
                                ui.Dialog(app.UIFigure, 'error', 'Não se trata da pasta "DataHub - POST", do SCH.');
                                return
                            end

                            app.DataHubPOST.Value = selectedFolder;
                            app.mainApp.General.fileFolder.DataHub_POST = selectedFolder;
    
                            ipcMainMatlabCallsHandler(app.mainApp, app, 'checkDataHubLampStatus')
                        end

                    case app.userPathButton
                        app.userPath.Value = selectedFolder;
                        app.mainApp.General.fileFolder.userPath = selectedFolder;
                end

                app.mainApp.General_I.fileFolder = app.mainApp.General.fileFolder;
                saveGeneralSettings(app)
                updatePanel_Folder(app)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCH';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Toolbar = uigridlayout(app.GridLayout);
            app.openDevTools = uiimage(app.Toolbar);
            app.openDevTools.ImageClickedFcn = createCallbackFcn(app, @ToolbarButtonPushed, true);
            app.openDevTools.Enable = 'off';
            app.openDevTools.Tooltip = {'Abre DevTools'};
            app.openDevTools.ImageSource = 'Debug_18.png';
            app.exportTable = uiimage(app.Toolbar);
            app.exportTable.ImageClickedFcn = createCallbackFcn(app, @ToolbarButtonPushed, true);
            app.exportTable.Enable = 'off';
            app.exportTable.Tooltip = {'Abre cópia de base dados no Excel'};
            app.exportTable.ImageSource = 'Sheet_32.png';
            app.SubTabGroup = uitabgroup(app.GridLayout);
            app.SubTabGroup.SelectionChangedFcn = createCallbackFcn(app, @SubTabGroup_TabSelectionChanged, true);
            app.SubTab1 = uitab(app.SubTabGroup);
            app.SubTab1.Title = 'ASPECTOS GERAIS';
            app.SubGrid1 = uigridlayout(app.SubTab1);
            app.versionInfoLabel = uilabel(app.SubGrid1);
            app.versionInfoLabel.Text = 'AMBIENTE:';
            app.tool_versionInfoRefresh = uiimage(app.SubGrid1);
            app.tool_versionInfoRefresh.ImageClickedFcn = createCallbackFcn(app, @Toolbar_AppEnvRefreshButtonPushed, true);
            app.tool_versionInfoRefresh.Enable = 'off';
            app.tool_versionInfoRefresh.Tooltip = {'Verifica atualizações'};
            app.tool_versionInfoRefresh.ImageSource = 'Refresh_18.png';
            app.versionInfo = uilabel(app.SubGrid1);
            app.versionInfo.Interpreter = 'html';
            app.versionInfo.Text = '';
            app.openAuxiliarAppAsDocked = uicheckbox(app.SubGrid1);
            app.openAuxiliarAppAsDocked.ValueChangedFcn = createCallbackFcn(app, @Config_GeneralParameterValueChanged, true);
            app.openAuxiliarAppAsDocked.Enable = 'off';
            app.openAuxiliarAppAsDocked.Text = 'Modo DOCK: módulos auxiliares abertos na janela principal do app';
            app.openAuxiliarApp2Debug = uicheckbox(app.SubGrid1);
            app.openAuxiliarApp2Debug.ValueChangedFcn = createCallbackFcn(app, @Config_GeneralParameterValueChanged, true);
            app.openAuxiliarApp2Debug.Enable = 'off';
            app.openAuxiliarApp2Debug.Text = 'Modo DEBUG';
            app.SubTab2 = uitab(app.SubTabGroup);
            app.SubTab2.Title = 'ANÁLISE';
            app.SubGrid2 = uigridlayout(app.SubTab2);
            app.config_MiscelaneousLabel1 = uilabel(app.SubGrid2);
            app.config_MiscelaneousLabel1.Text = 'ALGORITMO SUGESTÃO DE TOKENS';
            app.config_MiscelaneousPanel1 = uipanel(app.SubGrid2);
            app.config_MiscelaneousGrid1 = uigridlayout(app.config_MiscelaneousPanel1);
            app.config_nMinCharactersLabel = uilabel(app.config_MiscelaneousGrid1);
            app.config_nMinCharactersLabel.Text = 'Qtd. mínima caracteres:';
            app.config_nMinCharacters = uispinner(app.config_MiscelaneousGrid1);
            app.config_nMinCharacters.Limits = [2 4];
            app.config_nMinCharacters.RoundFractionalValues = 'on';
            app.config_nMinCharacters.ValueDisplayFormat = '%d';
            app.config_nMinCharacters.ValueChangedFcn = createCallbackFcn(app, @Analysis_ParameterValueChanged, true);
            app.config_nMinCharacters.Value = 2;
            app.config_nMinWordsLabel = uilabel(app.config_MiscelaneousGrid1);
            app.config_nMinWordsLabel.Interpreter = 'html';
            app.config_nMinWordsLabel.Text = 'Qtd. mínima <i>tokens</i>:';
            app.config_nMinWords = uidropdown(app.config_MiscelaneousGrid1);
            app.config_nMinWords.Items = {'20', '50', '100'};
            app.config_nMinWords.ValueChangedFcn = createCallbackFcn(app, @Analysis_ParameterValueChanged, true);
            app.config_nMinWords.Value = '20';
            app.config_MiscelaneousLabel2 = uilabel(app.SubGrid2);
            app.config_MiscelaneousLabel2.Text = 'ANOTAÇÃO DO TIPO "WORDCLOUD"';
            app.config_MiscelaneousPanel2 = uipanel(app.SubGrid2);
            app.config_MiscelaneousGrid2 = uigridlayout(app.config_MiscelaneousPanel2);
            app.config_WordCloudAlgorithmLabel = uilabel(app.config_MiscelaneousGrid2);
            app.config_WordCloudAlgorithmLabel.Text = 'Algoritmo gráfico:';
            app.config_WordCloudAlgorithm = uidropdown(app.config_MiscelaneousGrid2);
            app.config_WordCloudAlgorithm.Items = {'D3.js', 'MATLAB built-in'};
            app.config_WordCloudAlgorithm.ValueChangedFcn = createCallbackFcn(app, @Analysis_ParameterValueChanged, true);
            app.config_WordCloudAlgorithm.Enable = 'off';
            app.config_WordCloudAlgorithm.Value = 'D3.js';
            app.config_WordCloudColumnLabel = uilabel(app.config_MiscelaneousGrid2);
            app.config_WordCloudColumnLabel.Text = 'Pesquisa orientada à coluna:';
            app.config_WordCloudColumn = uidropdown(app.config_MiscelaneousGrid2);
            app.config_WordCloudColumn.Items = {'Modelo', 'Nome Comercial'};
            app.config_WordCloudColumn.ValueChangedFcn = createCallbackFcn(app, @Analysis_ParameterValueChanged, true);
            app.config_WordCloudColumn.Value = 'Modelo';
            app.config_SelectedTableColumnsLabel = uilabel(app.SubGrid2);
            app.config_SelectedTableColumnsLabel.Text = 'VISIBILIDADE DE COLUNAS';
            app.config_SelectedTableColumns = uitree(app.SubGrid2, 'checkbox');
            % Assign Checked Nodes
            app.config_SelectedTableColumns.CheckedNodesChangedFcn = createCallbackFcn(app, @Analysis_ParameterValueChanged, true);
            app.config_SearchModeDefaultParameters = uiimage(app.SubGrid2);
            app.config_SearchModeDefaultParameters.ImageClickedFcn = createCallbackFcn(app, @Analysis_DefaultParametersClicked, true);
            app.config_SearchModeDefaultParameters.Visible = 'off';
            app.config_SearchModeDefaultParameters.Tooltip = {'Volta à configuração inicial'};
            app.config_SearchModeDefaultParameters.ImageSource = 'Refresh_18.png';
            app.config_SearchModePanel = uibuttongroup(app.SubGrid2);
            app.config_SearchModePanel.SelectionChangedFcn = createCallbackFcn(app, @Analysis_ParameterValueChanged, true);
            app.config_SearchModeTokenSuggestion = uiradiobutton(app.config_SearchModePanel);
            app.config_SearchModeTokenSuggestion.Text = '<p style="text-align:justify; line-height:1.1;">Pesquisa orientada à palavra que está sendo escrita, sugerindo <i>tokens</i> relacionados.</p>';
            app.config_SearchModeTokenSuggestion.Interpreter = 'html';
            app.config_SearchModeTokenSuggestion.Value = true;
            app.config_SearchModeListOfWords = uiradiobutton(app.config_SearchModePanel);
            app.config_SearchModeListOfWords.Text = '<p style="text-align:justify; line-height:1.1;">Pesquisa orientada à uma lista de palavras separadas por vírgulas. Não há sugestão de <i>tokens</i> relacionados.</p>';
            app.config_SearchModeListOfWords.Interpreter = 'html';
            app.config_SearchModeLabel = uilabel(app.SubGrid2);
            app.config_SearchModeLabel.Text = 'MODO';
            app.SubTab3 = uitab(app.SubTabGroup);
            app.SubTab3.Title = 'PROJETO';
            app.SubGrid3 = uigridlayout(app.SubTab3);
            app.eFiscalizaLabel = uilabel(app.SubGrid3);
            app.eFiscalizaLabel.Text = 'INICIALIZAÇÃO eFISCALIZA';
            app.eFiscalizaRefresh = uiimage(app.SubGrid3);
            app.eFiscalizaRefresh.ImageClickedFcn = createCallbackFcn(app, @Config_ProjectRefreshImageClicked, true);
            app.eFiscalizaRefresh.Visible = 'off';
            app.eFiscalizaRefresh.Tooltip = {'Retorna às configurações iniciais'};
            app.eFiscalizaRefresh.ImageSource = 'Refresh_18.png';
            app.eFiscalizaPanel = uipanel(app.SubGrid3);
            app.eFiscalizaGrid = uigridlayout(app.eFiscalizaPanel);
            app.reportSystemLabel = uilabel(app.eFiscalizaGrid);
            app.reportSystemLabel.Text = 'Ambiente do sistema de gestão à fiscalização:';
            app.reportSystem = uidropdown(app.eFiscalizaGrid);
            app.reportSystem.Items = {'eFiscaliza', 'eFiscaliza TS'};
            app.reportSystem.ValueChangedFcn = createCallbackFcn(app, @Config_ProjectParameterValueChanged, true);
            app.reportSystem.Value = 'eFiscaliza';
            app.reportUnitLabel = uilabel(app.eFiscalizaGrid);
            app.reportUnitLabel.Text = 'Unidade responsável pela fiscalização:';
            app.reportUnit = uidropdown(app.eFiscalizaGrid);
            app.reportUnit.Items = {};
            app.reportUnit.ValueChangedFcn = createCallbackFcn(app, @Config_ProjectParameterValueChanged, true);
            app.reportUnit.Value = {};
            app.reportLabel = uilabel(app.SubGrid3);
            app.reportLabel.Text = 'RELATÓRIO';
            app.reportPanel = uipanel(app.SubGrid3);
            app.reportGrid = uigridlayout(app.reportPanel);
            app.reportDocTypeLabel = uilabel(app.reportGrid);
            app.reportDocTypeLabel.Text = 'Tipo de documento a gerar:';
            app.reportDocType = uidropdown(app.reportGrid);
            app.reportDocType.Items = {'Relatório de Atividades'};
            app.reportDocType.ValueChangedFcn = createCallbackFcn(app, @Config_ProjectParameterValueChanged, true);
            app.reportDocType.Value = 'Relatório de Atividades';
            app.SubTab4 = uitab(app.SubTabGroup);
            app.SubTab4.Title = 'MAPEAMENTO DE PASTAS';
            app.SubGrid4 = uigridlayout(app.SubTab4);
            app.DATAHUBGETLabel = uilabel(app.SubGrid4);
            app.DATAHUBGETLabel.Text = 'DATAHUB - GET:';
            app.DataHubGET = uieditfield(app.SubGrid4, 'text');
            app.DataHubGET.Editable = 'off';
            app.DataHubGETButton = uiimage(app.SubGrid4);
            app.DataHubGETButton.ImageClickedFcn = createCallbackFcn(app, @Config_FolderButtonPushed, true);
            app.DataHubGETButton.Tag = 'DataHub_POST';
            app.DataHubGETButton.Enable = 'off';
            app.DataHubGETButton.ImageSource = 'OpenFile_36x36.png';
            app.DATAHUBPOSTLabel = uilabel(app.SubGrid4);
            app.DATAHUBPOSTLabel.Text = 'DATAHUB - POST:';
            app.DataHubPOST = uieditfield(app.SubGrid4, 'text');
            app.DataHubPOST.Editable = 'off';
            app.DataHubPOSTButton = uiimage(app.SubGrid4);
            app.DataHubPOSTButton.ImageClickedFcn = createCallbackFcn(app, @Config_FolderButtonPushed, true);
            app.DataHubPOSTButton.Tag = 'DataHub_POST';
            app.DataHubPOSTButton.Enable = 'off';
            app.DataHubPOSTButton.ImageSource = 'OpenFile_36x36.png';
            app.userPathLabel = uilabel(app.SubGrid4);
            app.userPathLabel.Text = 'PASTA DO USUÁRIO:';
            app.userPath = uieditfield(app.SubGrid4, 'text');
            app.userPath.Editable = 'off';
            app.userPathButton = uiimage(app.SubGrid4);
            app.userPathButton.ImageClickedFcn = createCallbackFcn(app, @Config_FolderButtonPushed, true);
            app.userPathButton.Tag = 'userPath';
            app.userPathButton.Enable = 'off';
            app.userPathButton.ImageSource = 'OpenFile_36x36.png';
            app.DockModule = uigridlayout(app.GridLayout);
            app.DockModule.Visible = 'off';
            app.dockModule_Close = uiimage(app.DockModule);
            app.dockModule_Close.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Close.Tag = 'DRIVETEST';
            app.dockModule_Close.Tooltip = {'Fecha módulo'};
            app.dockModule_Close.ImageSource = 'Delete_12SVG_white.svg';
            app.dockModule_Undock = uiimage(app.DockModule);
            app.dockModule_Undock.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Undock.Tag = 'DRIVETEST';
            app.dockModule_Undock.Enable = 'off';
            app.dockModule_Undock.Tooltip = {'Reabre módulo em outra janela'};
            app.dockModule_Undock.ImageSource = 'Undock_18White.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end