classdef winSCH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroupMenu                    matlab.ui.container.Panel
        menu_Grid                       matlab.ui.container.GridLayout
        jsBackDoor                      matlab.ui.control.HTML
        menu_Help                       matlab.ui.control.Image
        menu_ControlPanelVisibility     matlab.ui.control.Image
        menu_searchPath                 matlab.ui.control.Image
        menu_userPathGrid               matlab.ui.container.GridLayout
        menu_userPath                   matlab.ui.control.DropDown
        menu_Button3                    matlab.ui.control.StateButton
        menu_Button2                    matlab.ui.control.StateButton
        menu_Separator1                 matlab.ui.control.Image
        menu_Button1                    matlab.ui.control.StateButton
        TabGroup                        matlab.ui.container.TabGroup
        Tab1_Home                       matlab.ui.container.Tab
        home_splashscreenGrid           matlab.ui.container.GridLayout
        home_splashscreenGIF            matlab.ui.control.Image
        home_splashscreenTitle          matlab.ui.control.Label
        Tab2_Search                     matlab.ui.container.Tab
        search_mainGrid                 matlab.ui.container.GridLayout
        search_Control                  matlab.ui.container.GridLayout
        search_ControlMenu              matlab.ui.container.GridLayout
        search_menuBtn3Grid             matlab.ui.container.GridLayout
        search_menuBtn3Icon             matlab.ui.control.Image
        search_menuBtn3Label            matlab.ui.control.Label
        search_menuBtn2Grid             matlab.ui.container.GridLayout
        search_menuBtn2Icon             matlab.ui.control.Image
        search_menuBtn2Label            matlab.ui.control.Label
        search_menuBtn1Grid             matlab.ui.container.GridLayout
        search_menuBtn1Icon             matlab.ui.control.Image
        search_menuBtn1Label            matlab.ui.control.Label
        search_menuUnderline            matlab.ui.control.Image
        search_ControlTabGroup          matlab.ui.container.TabGroup
        search_Tab1                     matlab.ui.container.Tab
        search_Tab1Grid                 matlab.ui.container.GridLayout
        search_ListOfProducts           matlab.ui.control.ListBox
        search_ListOfProductsAdd        matlab.ui.control.Image
        search_ListOfProductsLabel      matlab.ui.control.Label
        search_WordCloudRefreshGrid     matlab.ui.container.GridLayout
        search_WordCloudRefresh         matlab.ui.control.Image
        search_WordCloudPanel           matlab.ui.container.Panel
        search_AnnotationPanel          matlab.ui.container.Panel
        search_AnnotationGrid           matlab.ui.container.GridLayout
        search_AnnotationValue          matlab.ui.control.TextArea
        search_AnnotationValueLabel     matlab.ui.control.Label
        search_AnnotationAttribute      matlab.ui.control.DropDown
        search_AnnotationAttributeLabel  matlab.ui.control.Label
        search_AnnotationPanelAdd       matlab.ui.control.Image
        search_AnnotationPanelLabel     matlab.ui.control.Label
        search_ProductInfoPanel         matlab.ui.container.Panel
        search_ProductInfoGrid          matlab.ui.container.GridLayout
        search_ProductInfo              matlab.ui.control.HTML
        search_ToolbarListOfProducts    matlab.ui.control.Image
        search_ToolbarWordCloud         matlab.ui.control.Image
        search_ToolbarAnnotation        matlab.ui.control.Image
        search_ProductInfoLabel         matlab.ui.control.Label
        search_Tab2                     matlab.ui.container.Tab
        Tab2Grid                        matlab.ui.container.GridLayout
        config_htmlListView_2           matlab.ui.control.CheckBox
        filter_SecundaryPanel           matlab.ui.container.Panel
        filter_SecundaryGrid            matlab.ui.container.GridLayout
        filter_Status                   matlab.ui.control.DropDown
        filter_StatusCheckBox           matlab.ui.control.CheckBox
        filter_TradeName                matlab.ui.control.EditField
        filter_TradeNameCheckBox        matlab.ui.control.CheckBox
        filter_Model                    matlab.ui.control.EditField
        filter_ModelCheckBox            matlab.ui.control.CheckBox
        filter_Manufacturer             matlab.ui.control.EditField
        filter_ManufacturerCheckBox     matlab.ui.control.CheckBox
        filter_CNPJ                     matlab.ui.control.EditField
        filter_CNPJCheckBox             matlab.ui.control.CheckBox
        filter_Applicant                matlab.ui.control.EditField
        filter_ApplicantCheckBox        matlab.ui.control.CheckBox
        filter_ProductList              matlab.ui.control.DropDown
        filter_ProductListCheckBox      matlab.ui.control.CheckBox
        filter_Certification            matlab.ui.control.EditField
        filter_CertificationCheckBox    matlab.ui.control.CheckBox
        filter_SecundaryPanelLabel      matlab.ui.control.Label
        filter_PrimaryPanel             matlab.ui.container.ButtonGroup
        filter_PrimaryOption3           matlab.ui.control.RadioButton
        filter_PrimaryOption2           matlab.ui.control.RadioButton
        filter_PrimaryOption1           matlab.ui.control.RadioButton
        filter_PrimaryPanelLabel        matlab.ui.control.Label
        search_Tab3                     matlab.ui.container.Tab
        Tab3Grid                        matlab.ui.container.GridLayout
        notes_WordCloudPanel            matlab.ui.container.Panel
        notes_WordCloudGrid             matlab.ui.container.GridLayout
        notes_WordCloudColumn           matlab.ui.control.DropDown
        notes_WordCloudColumnLabel      matlab.ui.control.Label
        notes_WordCloudNumber           matlab.ui.control.DropDown
        notes_WordCloudNumberLabel      matlab.ui.control.Label
        ANOTAODOTIPOWORDCLOUDLabel      matlab.ui.control.Label
        config_SuggestionPanel          matlab.ui.container.Panel
        config_SuggestionGrid           matlab.ui.container.GridLayout
        search_nMinWords                matlab.ui.control.DropDown
        search_nMinWordsLabel           matlab.ui.control.Label
        search_nMinCharacters           matlab.ui.control.Spinner
        search_nMinCharactersLabel      matlab.ui.control.Label
        config_Label_3                  matlab.ui.control.Label
        search_ModePanel                matlab.ui.container.ButtonGroup
        search_ModeListOfWords          matlab.ui.control.RadioButton
        search_ModeTokenSuggestion      matlab.ui.control.RadioButton
        search_ModeLabel                matlab.ui.control.Label
        search_SelectedTableColumns     matlab.ui.container.CheckBoxTree
        config_Label_2                  matlab.ui.control.Label
        search_Document                 matlab.ui.container.GridLayout
        search_Suggestions              matlab.ui.control.ListBox
        search_Metadata                 matlab.ui.control.Label
        search_entryPointPanel          matlab.ui.container.Panel
        search_entryPointGrid           matlab.ui.container.GridLayout
        search_entryPointImage          matlab.ui.control.Image
        search_entryPoint               matlab.ui.control.EditField
        search_Table                    matlab.ui.control.Table
        search_nRows                    matlab.ui.control.Label
        Tab3_Report                     matlab.ui.container.Tab
        report_mainGrid                 matlab.ui.container.GridLayout
        report_Control                  matlab.ui.container.GridLayout
        menu_ControlGrid_2              matlab.ui.container.GridLayout
        menu_Button3Grid_2              matlab.ui.container.GridLayout
        menu_Button3Icon_2              matlab.ui.control.Image
        menu_Button3Label_2             matlab.ui.control.Label
        menu_Button2Grid_2              matlab.ui.container.GridLayout
        menu_Button2Icon_2              matlab.ui.control.Image
        menu_Button2Label_2             matlab.ui.control.Label
        menu_Button1Grid_2              matlab.ui.container.GridLayout
        menu_Button1Icon_2              matlab.ui.control.Image
        menu_Button1Label_2             matlab.ui.control.Label
        menu_Underline_2                matlab.ui.control.Image
        menu_TabGroup_2                 matlab.ui.container.TabGroup
        Tab1_2                          matlab.ui.container.Tab
        Tab1Grid_2                      matlab.ui.container.GridLayout
        report_SelectedProductPanel     matlab.ui.container.Panel
        report_SelectedProductGrid      matlab.ui.container.GridLayout
        ModeloEditField                 matlab.ui.control.EditField
        ModeloEditFieldLabel            matlab.ui.control.Label
        FabricanteEditField             matlab.ui.control.EditField
        FabricanteEditFieldLabel        matlab.ui.control.Label
        HomologaoEditField              matlab.ui.control.EditField
        HomologaoEditFieldLabel         matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        PRODUTOfontstylecolorredNOPASSVELfontDEHOMOLOGAOButton  matlab.ui.control.RadioButton
        PRODUTOfontstylecolorredNOfontHOMOLOGADOButton  matlab.ui.control.RadioButton
        PRODUTOHOMOLOGADOButton         matlab.ui.control.RadioButton
        report_addProduct               matlab.ui.control.Image
        report_Type_2                   matlab.ui.control.DropDown
        report_TypeLabel_2              matlab.ui.control.Label
        report_ComplementaryNotes       matlab.ui.control.TextArea
        report_ComplementaryNotesLabel  matlab.ui.control.Label
        report_MonetaryValue            matlab.ui.control.NumericEditField
        report_MonetaryValueLabel       matlab.ui.control.Label
        report_Quantity                 matlab.ui.control.NumericEditField
        report_QuantityLabel            matlab.ui.control.Label
        report_Type                     matlab.ui.control.DropDown
        report_TypeLabel                matlab.ui.control.Label
        search_ListOfProductsLabel_2    matlab.ui.control.Label
        search_ListOfProducts_2         matlab.ui.control.ListBox
        Tab2_2                          matlab.ui.container.Tab
        Tab3_2                          matlab.ui.container.Tab
        report_Document                 matlab.ui.container.GridLayout
        report_ListOfProducts           matlab.ui.control.Table
        report_ListOfProductsLabel      matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        ContextMenu_DeleteFcn           matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        General

        executationMode                                                     % 'built-in' | 'desktopApp' | 'webApp'
        rootFolder
        userPath

        startupTimer
        customizationTimer

        % O MATLAB não renderiza alguns dos componentes de abas (do TabGroup) 
        % não visíveis. E a customização de componentes, usando a lib ccTools, 
        % somente é possível após a sua renderização. Controla-se a aplicação 
        % da customizaçao por meio dessa propriedade jsBackDoorFlag.
        jsBackDoorFlag   = true(3, 1);

        rawDataTable
        releasedData
        cacheData

        annotationTable
        listOfProducts
        
        previousSearch   = ''
        previousItemData = 0

        wordCloudChart
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function startupTimerCreation(app)
            app.startupTimer = timer("ExecutionMode", "fixedSpacing", ...
                                     "StartDelay",    1.5,            ...
                                     "Period",        .1,             ...
                                     "TimerFcn",      @(~,~)app.startupTimerFcn);
            start(app.startupTimer)
        end


        %-----------------------------------------------------------------%
        function customizationTimerCreation(app)
            app.customizationTimer = timer("ExecutionMode", "singleShot", ...
                                           "StartDelay",    1.5,          ...
                                           "TimerFcn",      @(~,~)app.layout_UpdateTableColumns);
        end


        %-----------------------------------------------------------------%
        function startupTimerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.startupTimer)
                
                jsBackDoor_DeleteProgressDialog(app)
                
                ReadFiles(app)
                fcn.parpoolCheck()
                
                app.search_entryPoint.Enable = 1;
                search_ConfigTableColumns(app)
            end
        end
        

        %-----------------------------------------------------------------%
        function ReadFiles(app)
            % app.General
            app.General = jsondecode(fileread(fullfile(app.rootFolder, 'Settings', 'GeneralSettings.json')));

            % app.rawDataTable
            % app.releaseData
            % app.cacheData
            switch app.executationMode
                case {'built-in', 'desktopApp'}                             % MATLAB | MATLAB RUNTIME
                    appMode  = 'desktopApp';
                    filePath = fullfile(app.rootFolder, app.General.desktopApp.filePath);
                    fileName = app.General.desktopApp.fileName;

                case 'webApp'                                               % MATLAB WEBSERVER + RUNTIME
                    computerName = getenv('COMPUTERNAME');
                    switch computerName
                        case app.General.webServer.computerName
                            appMode  = 'webServer';
                            filePath = eval(app.General.webServer.filePath);
                            fileName = app.General.webServer.fileName;
        
                        case app.General.deployServer.computerName
                            appMode  = 'deployServer';
                            filePath = eval(app.General.deployServer.filePath);
                            fileName = app.General.deployServer.fileName;

                        otherwise
                            error('Unexpected server name')
                    end
            end
            fileFullPath = fullfile(filePath, fileName);

            % Aqui apenas registrando o path do arquivo de anotações,
            % possibilitando edição ao final da sessão do app.
            app.General.Annotation.filePath = fullfile(filePath, app.General.Annotation.fileName);
            
            [app.rawDataTable, ...
             app.releasedData, ...
             app.cacheData,    ...
             matFullFile] = fcn.ReadSCHTable(fileFullPath, class.Constants.cacheColumns);

            if ~isempty(matFullFile)
                [~, fileMATName] = fileparts(fileName);
                fileMATName      = [fileMATName '.mat'];

                eval(sprintf('app.General.%s.fileName = ''%s'';', appMode, fileMATName))
                fcn.updateFile_GeneralSettings(app.General, app.rootFolder)
            end

            % app.annotationTable
            annotationFileSource = app.General.Annotation.fileSource;
            annotationFilePath   = app.General.Annotation.filePath;
            [~, tempSCHLinks]    = fcn.PublicLinks(app.rootFolder);
            annotationURL        = tempSCHLinks.Annotation;

            [app.annotationTable, ...
             msgWarning]         = class.Annotation.read(annotationFileSource, annotationFilePath, annotationURL);
            if ~isempty(msgWarning)
                layout.modalWindow(app.UIFigure, 'warning', msgWarning);
            end

            % !! PONTO DE EVOLUÇÃO !!
            % INSERIR ESSA INFORMAÇÃO NO ÍCONE "I", NO TOPO SUPERIOR
            % DIREITO.
            % executionModeInfo    = struct('group', 'MODO',                                        ...
            %                               'value', struct('executationMode', app.executationMode, ...
            %                                               'appMode',         appMode));
            % executionModeInfo(2) = struct('group', 'PATH',                                   ...
            %                               'value', struct('rootFolder',      app.rootFolder, ...
            %                                               'fileFullPath',    fileFullPath));
            % app.executionTxt.HTMLSource = fcn.htmlCode_appsStyle(executionModeInfo);
            % !! PONTO DE EVOLUÇÃO !!
        end


        %-----------------------------------------------------------------%
        function search_ConfigTableColumns(app)
            allColumns      = class.Constants.allColumns;
            [~, sortIndex]  = sort(lower(allColumns));
            GUIAllColumns   = allColumns(sortIndex);

            listOfTreeNodes = cellfun(@(x) uitreenode(app.search_SelectedTableColumns, 'Text', x), GUIAllColumns);
            isStaticColumn  = ismember(GUIAllColumns, class.Constants.staticColumns);            

            app.search_SelectedTableColumns.CheckedNodes = listOfTreeNodes(isStaticColumn);
            addStyle(app.search_SelectedTableColumns, class.Constants.listBoxStyle, 'node', listOfTreeNodes(isStaticColumn))
        end


        %-----------------------------------------------------------------%
        function [columnNames, columnWidth] = search_ColumnNames(app)
            checkedNodes = app.search_SelectedTableColumns.CheckedNodes;
            staticColums = class.Constants.staticColumns;
            columnNames  = unique([staticColums, arrayfun(@(x) x.Text, checkedNodes, 'UniformOutput', false)'], 'stable');

            allColumns   = class.Constants.allColumns;
            widthColumns = class.Constants.widthColumns;

            columnWidth  = {};
            for ii = 1:numel(columnNames)
                columnName      = columnNames{ii};
                columnIndex     = find(strcmp(allColumns, columnName), 1);
                
                columnWidth{ii} = widthColumns{columnIndex};
            end
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource           = sprintf('<!DOCTYPE html>\n<html>\n<head>\n<script>\n%s\n</script>\n</head>\n\n<body>\n</body>\n</html>', fileread(fullfile(ccTools.Path, 'css&js', 'compCustomization.js')));
            app.jsBackDoor.HTMLEventReceivedFcn = @(~, evt)jsBackDoor_keyDownListener(app, evt);
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_DeleteProgressDialog(app)
            % Força a exclusão da tela de progresso da inicialização de um 
            % webapp, caso aplicável.
            sendEventToHTMLSource(app.jsBackDoor, "delProgressDialog");
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app, tabIndex)
            switch tabIndex
                case 1 % HOME

                case 2 % SEARCH
                    if app.jsBackDoorFlag(tabIndex)
                        app.jsBackDoorFlag(tabIndex) = false;
        
                        % Cria keydown listeners, em JS, para os componentes descritos
                        % em objList.
                        objList = {app.search_entryPoint, app.search_Suggestions};
                        for ii = 1:numel(objList)
                            objList{ii}.UserData = struct(objList{ii}).Controller.ViewModel.Id;
                        end            
                        sendEventToHTMLSource(app.jsBackDoor, "addKeyDownListener", struct('DataName', 'app.search_entryPoint',  'DataTag', app.search_entryPoint.UserData));
                        sendEventToHTMLSource(app.jsBackDoor, "addKeyDownListener", struct('DataName', 'app.search_Suggestions', 'DataTag', app.search_Suggestions.UserData));
            
                        % Aplica customizações estéticas ao(s) componente(s).
                        ccTools.compCustomizationV2(app.jsBackDoor, app.search_ControlTabGroup,      'borderWidth', '0px');
                        ccTools.compCustomizationV2(app.jsBackDoor, app.search_entryPoint,           'borderWidth', '0px');
                        ccTools.compCustomizationV2(app.jsBackDoor, app.search_WordCloudRefreshGrid, 'backgroundColor', 'transparent');
                        
                        drawnow
                    end

                case 3 % REPORT

            end
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_keyDownListener(app, event)

            % Foi adicionado o evento JS-keydown das teclas ["ArrowUp", "ArrowDown", "Enter", "Escape"]
            % aos componentes app.search_entryPoint (matlab.ui.control.EditField) e app.search_Suggestions
            % (matlab.ui.control.ListBox) usando o JS-backdoor app.jsBackDoor (matlab.ui.control.HTML).
    
            % Em relação aos callbacks configuráveis no próprio MATLAB:
            % - matlab.ui.control.EditField 
            %   (a) Possui os eventos "ValueChangedFcn" e "ValueChangingFcn".
            %   (b) Não responde à tecla "Escape".
            %   (c) Responde às teclas "ArrowUp" e "ArrowDown", controlando a posição 
            %       do cursor (início e fim, respectivamente)
            %   (d) Reponde à tecla "Enter", executando "ValueChangingFcn" e "ValueChangedFcn", 
            %       nesse ordem.
    
            % - matlab.ui.control.ListBox
            %   (a) possui os eventos "ValueChangedFcn", "ClickedFcn" e "DoubleClickedFcn".
            %   (b) Responde às teclas "ArrowUp" e "ArrowDown", executando "ValueChangedFcn", 
            %       desde que não estejam selecionadas as suas "bordas" (valor 1 e "ArrowUp", 
            %       ou valor n e "ArrowDown", por exemplo).
    
            % Num eventual clique de uma das teclas ["ArrowUp", "ArrowDown", "Enter", "Escape"],
            % o trigger do evento JS-keydown ocorre antes do trigger dos eventos padrões dos 
            % componentes matlab.ui.control.EditField e matlab.ui.control.ListBox.
    
            % Isso é bom, mas ruim por criar uma complexidade extra! :(
    
            % Quando altero o conteúdo de app.search_entryPoint, sem alterar o seu foco, será executado 
            % o evento "ValueChangingFcn". Se pressiono a tecla "Enter", será executada essa função 
            % (jsBackDoor_keyDownListener) antes de atualizar a propriedade "Value" do app.search_entryPoint.
    
            % Por conta disso, é essencial inserir waitfor(app.search_entryPoint, 'Value')

            switch event.HTMLEventData
                case 'app.search_entryPoint'
                    focus(app.jsBackDoor)

                    switch event.HTMLEventName
                        case 'Escape'
                            set(app.search_Suggestions, Visible=0, Value={})

                            suggestion_CheckIfEntryPointNeedsUpdate(app)
                            if numel(app.search_entryPoint.Value) <= 1
                                set(app.search_entryPoint, 'FontColor', [.8,.8,.8], 'Value', 'O que você quer pesquisar?')
                            end

                        otherwise
                            % Ao trocar o foco de app.search_entryPoint para app.jsBackDoor, o MATLAB executa 
                            % logicamente a operação: app.search_entryPoint.Value = app.search_entryPoint.ChangingValue
                            % Ao inserir waitfor, teremos a garantia de usar o valor atual do objeto...
                            suggestion_CheckIfEntryPointNeedsUpdate(app)
                            if numel(app.search_entryPoint.Value) <= 1
                                sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));

                            else
                                switch event.HTMLEventName
                                    case 'ArrowDown'
                                        if app.search_ModeTokenSuggestion.Value
                                            app.previousItemData = 1;
                
                                            set(app.search_Suggestions, 'Visible', 1, 'Value', 1)
                                            scroll(app.search_Suggestions, "top")
                                            focus(app.search_Suggestions)
                                        end
                    
                                    case 'ArrowUp'
                                        if app.search_ModeTokenSuggestion.Value
                                            nMaxValues = numel(app.search_Suggestions.Items);
    
                                            app.previousItemData = nMaxValues;            
                                            set(app.search_Suggestions, 'Visible', 1, 'Value', nMaxValues)
                                            scroll(app.search_Suggestions, "bottom")
                                            focus(app.search_Suggestions)
                                        end

                                    case 'Enter'                                        
                                        set(app.search_Suggestions, Visible=0, Value={})
                                        search_EntryPointImageClicked(app)
                                        % sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));
                                end
                            end
                    end

                %---------------------------------------------------------%
                case 'app.search_Suggestions'
                    switch event.HTMLEventName
                        case 'ArrowDown'
                            nMaxValues = numel(app.search_Suggestions.Items);

                            if (app.previousItemData == nMaxValues) && (app.search_Suggestions.Value == nMaxValues)
                                app.previousItemData = 0;
                                
                                set(app.search_Suggestions, Visible=0, Value={})
                                sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));
                            else
                                app.previousItemData = app.search_Suggestions.Value;
                            end
        
                        case 'ArrowUp'
                            if (app.previousItemData == 1) && (app.search_Suggestions.Value == 1)
                                app.previousItemData = 0;

                                set(app.search_Suggestions, Visible=0, Value={})
                                sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));
                            else
                                app.previousItemData = app.search_Suggestions.Value;
                            end
        
                        case 'Enter'
                            eventValue = app.search_Suggestions.Items{app.search_Suggestions.Value};

                            app.search_entryPoint.Value = eventValue;
                            suggestion_EntryPointValueChanging(app, eventValue, false)
                            
                            sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));

                        case 'Escape'
                            set(app.search_Suggestions, Visible=0, Value={})
                    end
            end
            drawnow
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_HTMLEventReceivedFcn(app, event)
            switch event.Source
                case app.search_List
                    % app.search_ProductInfo.HTMLSource = event.HTMLEventData;
            end
        end


        %-----------------------------------------------------------------%
        function cacheColumnName = suggestion_FindCache_ColumnName(app, columnName)
            cacheColumnName = sprintf('_%s', columnName);
            if ~ismember(cacheColumnName, app.rawDataTable.Properties.VariableNames)
                cacheColumnName = columnName;
            end
        end


        %-----------------------------------------------------------------%
        function cacheStringList = suggestion_FindCache_StringListOfSelectedColumn(app, listOfColumns)
            cacheIndex      = find(strcmp(listOfColumns, {app.cacheData.Column}), 1);
            cacheStringList = app.cacheData(cacheIndex).uniqueValues;
        end


        %-----------------------------------------------------------------%
        function cacheTokenList = suggestion_FindCache_TokenListOfSelectedColumn(app, listOfColumns)
            cacheIndex     = find(strcmp(listOfColumns, {app.cacheData.Column}), 1);
            cacheTokenList = app.cacheData(cacheIndex).uniqueTokens;
        end


        %-----------------------------------------------------------------%
        function suggestion_EntryPointValueChanging(app, eventValue, panelVisibility)
            value2Search = fcn.PreProcessedData(eventValue);

            if numel(value2Search) < app.search_nMinCharacters.Value
                set(app.search_Suggestions, Visible=0, Items={}, ItemsData=[])
                set(app.search_entryPointImage, Enable=0)
            else
                set(app.search_entryPointImage, Enable=1)
                if ~strcmp(eventValue, app.previousSearch)
                    suggestion_Algorithm(app, value2Search, panelVisibility)                    
                end
            end

            app.previousSearch = eventValue;
        end


        %-----------------------------------------------------------------%
        function suggestion_CheckIfEntryPointNeedsUpdate(app)
            if ~strcmp(app.search_entryPoint.Value, app.previousSearch)
                waitfor(app.search_entryPoint, 'Value')
            end
        end


        %-----------------------------------------------------------------%
        function suggestion_Algorithm(app, value2Search, panelVisibility)
            listOfColumns   = primaryFilter_ListOfColumns(app);
            cacheStringList = suggestion_FindCache_StringListOfSelectedColumn(app, listOfColumns);
            cacheTokenList  = [];

            nMaxValues      = str2double(app.search_nMinWords.Value);
            
            algorithmsOrder = {'startsWith', 'Contains', 'Levenshtein'};
            redFontFlag     = false;

            idxFiltered     = [];
            for kk = algorithmsOrder
                methodName = char(kk);
                
                if strcmp(methodName, 'Levenshtein')
                    if numel(idxFiltered) >= nMaxValues
                        break
                    end

                    if isempty(idxFiltered)
                        redFontFlag = true;
                    end

                    cacheTokenList = suggestion_FindCache_TokenListOfSelectedColumn(app, listOfColumns);
                end

                idxFiltered = fcn.getSimilarStrings(methodName, cacheStringList, cacheTokenList, value2Search, idxFiltered, nMaxValues);
            end

            set(app.search_Suggestions, 'Visible', panelVisibility, 'Value', {}, 'Items', cacheStringList(idxFiltered), 'ItemsData', 1:numel(idxFiltered))
            layout_EntryPointColor(app, redFontFlag)
        end


        %-----------------------------------------------------------------%
        function [selectedRow, selectedHom, selectedHomUITableIndex, showedHom] = notes_SelectedRow(app)
            selectedRow = app.search_Table.Selection;

            if ~isempty(selectedRow)
                selectedHom             = unique(app.search_Table.Data.("Homologação")(selectedRow), 'stable');
                selectedHomUITableIndex = find(ismember(app.search_Table.Data.("Homologação"), selectedHom));
            else
                selectedHom             = {};
                selectedHomUITableIndex = [];
            end

            showedHom = app.search_ProductInfo.Tag;
        end


        %-----------------------------------------------------------------%
        function relatedAnnotationTable = notes_RelatedCacheTable(app, showedHom)
            annotationLogical      = strcmp(app.annotationTable.("Homologação"), showedHom);
            relatedAnnotationTable = app.annotationTable(annotationLogical, :);
        end


        %-----------------------------------------------------------------%
        function notes_Add2Cache(app, showedHom, attributeName, attributeValue, wourdCloudRefreshTag)
            % !! PONTO DE EVOLUÇÃO !!
            % IMPLEMENTADA ADICIONAR UMA ANOTAÇÃO, ALÉM DE EDITAR UMA ANOTAÇÃO 
            % DE WORDCLOUD. PENDETE EXCLUSÃO DE UMA ANOTAÇÃO, ALÉM DA EDIÇÃO
            % DOS OUTROS TIPOS DE ANOTAÇÃO.
            newRowTable = table({char(matlab.lang.internal.uuid())},   ...
                                {datestr(now, 'dd/mm/yyyy HH:MM:SS')}, ...
                                {getenv("COMPUTERNAME")},              ...
                                {getenv("USERNAME")},                  ...
                                {showedHom},                         ...
                                {attributeName},                       ...
                                {attributeValue},                      ...
                                2, 'VariableNames', class.Constants.notesColumns);

            [annotationFlag, app.annotationTable] = class.Annotation.addRow(app.annotationTable, newRowTable, wourdCloudRefreshTag);

            if annotationFlag
                if wourdCloudRefreshTag
                    idx = find(strcmp(app.annotationTable.("Homologação"), showedHom) & strcmp(app.annotationTable.("Atributo"), 'WordCloud'));
                    app.annotationTable(idx(1:end-1), :) = [];
                end
                relatedAnnotationTable   = notes_RelatedCacheTable(app, showedHom);

                selectedHomRawTableIndex = find(strcmp(app.rawDataTable.("Homologação"), showedHom));
                layout_selectedHomInfo(app, showedHom, selectedHomRawTableIndex, relatedAnnotationTable)

                % Ajusta apenas o estilo de anotação do registro...
                layout_TableRemoveStyle(app, 'cell')
                layout_TableAnnotationIcon(app)
                
            else
                layout.modalWindow(app.UIFigure, 'warning', sprintf('Conjunto atributo/valor já consta como anotação do registro %s.', showedHom));
            end
            % !! PONTO DE EVOLUÇÃO !!
        end


        %-----------------------------------------------------------------%
        function listOfColumns = primaryFilter_ListOfColumns(app)
            selectedButton = app.filter_PrimaryPanel.SelectedObject;
            listOfColumns  = selectedButton.Text;

            % if app.filter_PrimaryOption4.Value
            %     listOfColumns = [listOfColumns, {'Annotation'}];
            % end
        end


        %-----------------------------------------------------------------%
        function primaryFilter_Filtering(app, words2Search, listOfColumns)
            d = layout.modalWindow(app.UIFigure, 'ccTools.ProgressDialog');

            % Rotina de inicialização dos objetos relacionados aos filtros 
            % secundários.
            set(findobj(app.filter_SecundaryGrid, 'Type', 'uicheckbox'),  'Value', 0,  'Enable', 1)
            set(findobj(app.filter_SecundaryGrid, 'Type', 'uieditfield'), 'Value', '', 'Enable', 0)
            set(findobj(app.filter_SecundaryGrid, 'Type', 'uidropdown'),  'Items', {}, 'Enable', 0)

            % Filtragem primária.
            listOfCacheColumns = cellfun(@(x) sprintf('_%s', x), strsplit(listOfColumns, ' | '), 'UniformOutput', false);
            
            primaryTempIndex   = [];
            for ii = 1:numel(words2Search)
                if app.search_ModeTokenSuggestion.Value
                    primaryTempIndex = [primaryTempIndex; find(any(strcmp(app.rawDataTable{:, listOfCacheColumns}, words2Search{ii}), 2))];
                
                else
                    primaryTempIndex = [primaryTempIndex; find(any(contains(app.rawDataTable{:, listOfCacheColumns}, words2Search{ii}), 2))];
                end
            end
            primaryTempIndex = unique(primaryTempIndex, 'stable');

            % Adicionando os registros que possuem o mesmo número de "Homologação", 
            % mas não contém as palavras pesquisadas. A primeira abordagem
            % (comentada) é muito mais rápida, mas se perde a ORDEM dos registros, 
            % infelizmente. Por isso, escolhida a abordagem com cellfun e
            % vertcat concatenados.
            primaryHom       = unique(app.rawDataTable(primaryTempIndex,:).("Homologação"), 'stable');
          
          % primaryIndex     = find(ismember(app.rawDataTable.("Homologação"), primaryHom));            
            primaryCellIndex = cellfun(@(x) find(strcmp(app.rawDataTable.("Homologação"), x)), primaryHom, 'UniformOutput', false);
            primaryIndex     = vertcat(primaryCellIndex{:});

            GUIColumns       = search_ColumnNames(app);
            
            set(app.search_Table, 'Data',      app.rawDataTable(primaryIndex, GUIColumns), ...
                                  'UserData',  struct('primaryIndex', primaryIndex, 'secundaryIndex', primaryIndex))

            % Cria chart para a nuvem de palavras...
            if isempty(app.wordCloudChart)
                wordCloud_ChartCreation(app)
            end
                        
            % Renderiza em tela o número de linhas, além de selecionar a primeira 
            % linha da tabela, caso a pesquisa retorne algo.
            layout_TableNumberOfRows(app)
            layout_TableInitialSelection(app, true)

            % Aplica estilo na tabela...
            layout_TableAddStyle(app)

            delete(d)
            drawnow
        end


        %-----------------------------------------------------------------%
        % Notação:
        % - selectedHom:        é uma lista dos registros de homologações selecionados na tabela. (cell array)
        % - showedHom:          é o registro de homologação cujos dados são apresentados no painel de controle (metadados, nuvem de palavras etc).
        % - selected2showedHom: selectedHom{1} se transformando em showedHom...
        %-----------------------------------------------------------------%
        function layout_selectedHomInfo(app, selected2showedHom, selectedHomRawTableIndex, relatedAnnotationTable)
            if isempty(selected2showedHom)
                htmlSource = 'Warning.html';
            else
                htmlSource = fcn.htmlCode_rowTableInfo(app.rawDataTable(selectedHomRawTableIndex, :), relatedAnnotationTable);
            end
            set(app.search_ProductInfo, 'HTMLSource', htmlSource, 'Tag', selected2showedHom)
        end


        %-----------------------------------------------------------------%
        function search_PanelVisibility(app)
            showedHom = app.search_ProductInfo.Tag;
            if ~isempty(showedHom) && app.search_Tab1Grid.RowHeight{6}
                app.search_AnnotationPanelAdd.Visible = 1;
            else
                app.search_AnnotationPanelAdd.Visible = 0;
            end
        end


        %-----------------------------------------------------------------%
        function layout_EntryPointColor(app, redFlag)
            if redFlag
                fontColor = [1,0,0];
            else
                fontColor = [0,0,0];
            end
            app.search_entryPoint.FontColor = fontColor;
            drawnow
        end


        %-----------------------------------------------------------------%
        function layout_TableVisibility(app)
            if ~app.search_Table.Visible
                layout_UpdateTableColumns(app)

                app.search_Table.Visible     = 1;
                app.search_Metadata.Visible  = 1;
                app.search_nRows.Visible     = 1;

                app.menu_ControlPanelVisibility.Enable = 1;
                app.menu_Button3.Enable      = 1;
            end
        end


        %-----------------------------------------------------------------%
        function layout_UpdateTableColumns(app)
            if strcmp(app.customizationTimer.Running, 'on')
                stop(app.customizationTimer)
            end

            ccTools.compCustomizationV2(app.jsBackDoor, app.search_Table, 'fontSize', '10px', 'paddingTop', '0px');
        end


        %-----------------------------------------------------------------%
        function layout_ListOfProductsVisibility(app)
            if ~app.report_ListOfProducts.Visible
                ccTools.compCustomizationV2(app.jsBackDoor, app.report_ListOfProducts, 'fontSize', '10px', 'paddingTop', '0px');

                app.report_ListOfProductsLabel.Visible = 1;
                app.report_ListOfProducts.Visible      = 1;                
            end
        end


        %-----------------------------------------------------------------%
        function layout_TableNumberOfRows(app)
            nRows = height(app.search_Table.Data);
            app.search_nRows.Text = sprintf('# %d ', nRows);
        end


        %-----------------------------------------------------------------%
        function layout_TableInitialSelection(app, focusFlag)
            if isempty(app.search_Table.Data)
                app.search_Table.Selection = [];
            else
                app.search_Table.Selection = 1;
            end            
            search_TableSelectionChanged(app)

            if focusFlag
                focus(app.search_Table)
            end
        end


        %-----------------------------------------------------------------%
        function layout_TableAddStyle(app)
            layout_TableRemoveStyle(app, 'all')

            layout_TableRowStriping(app)
            layout_TablePrimaryColumnBackground(app)
            layout_TableAnnotationIcon(app)
        end


        %-----------------------------------------------------------------%
        function layout_TableRemoveStyle(app, styleType)
            switch styleType
                case 'all'
                    removeStyle(app.search_Table)

                otherwise
                styleTypeIndex  = find(strcmp(cellstr(app.search_Table.StyleConfigurations.Target), styleType));
                if ~isempty(styleTypeIndex)
                    removeStyle(app.search_Table, styleTypeIndex)
                end
            end
        end


        %-----------------------------------------------------------------%
        function layout_TableRowStriping(app)
            [~, ~, uniqueHomIndex] = unique(app.search_Table.Data.("Homologação"), 'stable');
            listOfRows             = find(~mod(uniqueHomIndex, 2));

            if ~isempty(listOfRows)
                addStyle(app.search_Table, class.Constants.tblStyle_row, 'row', listOfRows)
            end
        end
        
        
        %-----------------------------------------------------------------%
        function layout_TablePrimaryColumnBackground(app)
            selectedButton = app.filter_PrimaryPanel.SelectedObject;
            listOfColumns  = strsplit(selectedButton.Text, ' | ');

            addStyle(app.search_Table, class.Constants.tblStyle_col, 'column', listOfColumns)
        end


        %-----------------------------------------------------------------%
        function layout_TableAnnotationIcon(app)
            % Posição da coluna "Homologação".
            homColumnIndex    = find(strcmp(app.search_Table.Data.Properties.VariableNames, 'Homologação'), 1);

            % Valores únicos de homologação e seus índices...
            [listOfHom, ...
             lisOfHomIndex]   = unique(app.search_Table.Data.("Homologação"), 'stable');
            
            % Identifica registros para os quais existe anotação registrada, 
            % aplicando o estilo.
            annotationLogical = ismember(listOfHom, unique(app.annotationTable.("Homologação")));
            annotationIndex   = lisOfHomIndex(annotationLogical);
            listOfCells       = [annotationIndex, repmat(homColumnIndex, numel(annotationIndex), 1)];

            if ~isempty(listOfCells)
                if app.filter_PrimaryOption1.Value
                    s = class.Constants.tblStyle_cell2;
                else
                    s = class.Constants.tblStyle_cell1;
                end
                addStyle(app.search_Table, s, "cell", listOfCells)
            end
        end


        %-----------------------------------------------------------------%
        function wordCloud_ChartCreation(app)
            t = tiledlayout(app.search_WordCloudPanel, 1, 1, 'Padding', 'tight');
            app.wordCloudChart = wordcloud(t, wordCloud_EmptyTable(app), 'Word', 'Count',                  ...
                                           'MaxDisplayWords', str2double(app.notes_WordCloudNumber.Value), ...
                                           'Title', '', 'Units', 'normalized', 'Position', [0,0,1,1]);
        end


        %-----------------------------------------------------------------%
        function emptyTable = wordCloud_EmptyTable(app)
            emptyTable = table('Size',          [0,2],                ...
                               'VariableTypes', {'string', 'double'}, ...
                               'VariableNames', {'Word', 'Count'});
        end


        %-----------------------------------------------------------------%
        function status = wordCloud_CheckCache(app, selectedHom, relatedTable)
            status = false;

            wordCloudLogical = strcmp(relatedTable.("Atributo"), 'WordCloud');
            relatedTable     = relatedTable(wordCloudLogical, :);

            if isempty(relatedTable) || any(wordCloudLogical) && ~strcmp(app.search_WordCloudPanel.Tag, selectedHom)
                status = true;
            end
        end


        %-----------------------------------------------------------------%
        function status = wordCloud_PlotUpdate(app, showedHom, wourdCloudRefreshTag, dialogProgressFlag)
            status = true;

            if dialogProgressFlag
                d = layout.modalWindow(app.UIFigure, 'ccTools.ProgressDialog');
            end

            if wourdCloudRefreshTag
                wordCloudIndex         = [];
            else
                relatedAnnotationTable = notes_RelatedCacheTable(app, showedHom);
                wordCloudIndex         = find(strcmp(relatedAnnotationTable.("Atributo"), 'WordCloud'), 1);
            end

            listOfWordsTable = [];
            if ~isempty(wordCloudIndex)
                listOfWordsCSV         = relatedAnnotationTable.("Valor"){wordCloudIndex};
                listOfWordsTable       = struct2table(regexp(listOfWordsCSV, '\s?(?<Word>[^,:]+): (?<Count>\d+)', 'names'));
                listOfWordsTable.Count = cellfun(@(x) str2double(x), listOfWordsTable.Count);

            else
                % try
                    word2Search        = wordCloud_Word2Search(app, showedHom);
                % catch ME
                %     if exist('d', 'var')
                %         delete(d)
                %     end
                %     layout.modalWindow(app.UIFigure, 'warning', ME.message);
                % 
                %     status = false;
                %     return
                % end
                listOfWords = fcn.getRelatedWords(word2Search);

                if ~isempty(listOfWords)
                    nMaxWords        = str2double(app.notes_WordCloudNumber.Value);
                    listOfWordsTable = wordCloudCounts(listOfWords);

                    if height(listOfWordsTable) > nMaxWords
                        listOfWordsTable = listOfWordsTable(1:nMaxWords,:);
                    end

                    % Adicionar a nova informação ao cache temporário,
                    % substituindo os caracteres especiais "," e ":".
                    listOfWordsCSV = char(strjoin(replace(listOfWordsTable.Word, {',', ':'}, {'_', '_'}) + ": " + listOfWordsTable.Count, ', '));
                    notes_Add2Cache(app, showedHom, 'WordCloud', listOfWordsCSV, wourdCloudRefreshTag)
                end
            end

            if ~isempty(listOfWordsTable)                    
                app.wordCloudChart.SourceTable = listOfWordsTable;
                app.search_WordCloudPanel.Tag  = showedHom;
                drawnow
            end

            if exist('d', 'var')
                delete(d)
            end
        end


        %-----------------------------------------------------------------%
        function word2Search = wordCloud_Word2Search(app, showedHom)
            selectedRow = find(strcmp(app.search_Table.Data.("Homologação"), showedHom), 1);
            listOfWords = {char(app.search_Table.Data.("Modelo")(selectedRow)), ...
                           char(app.search_Table.Data.("Nome Comercial")(selectedRow))};

            switch app.notes_WordCloudColumn.Value
                case 'Modelo';         idx1 = 1;
                case 'Nome Comercial'; idx1 = 2;
            end
            word2Search = listOfWords{idx1};

            if isempty(word2Search)
                idx2 = setdiff([1 2], idx1);
                word2Search = listOfWords{idx2};

                if isempty(word2Search)
                    error('Registro %s não possui cadastrado "Modelo" ou "Nome Comercial", inviabilizando consulta à internet.', showedHom)
                else
                    listOfColumns = {'Modelo', 'Nome Comercial'};
                    layout.modalWindow(app.UIFigure, 'warning', sprintf('O registro %s não possui cadastrado "%s". Dessa forma, consulta à internet foi realizada usando o seu "%s".', showedHom, listOfColumns{idx1}, listOfColumns{idx2}));
                end
            end
        end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            try
                % PATH SEARCH
                app.rootFolder = fileparts(mfilename('fullpath'));

                if isdeployed
                    [~, result]       = system('path');
                    executationFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
    
                    if isfile(fullfile(executationFolder, sprintf('%s.exe', class.Constants.appName)))
                        app.executationMode = 'desktopApp';
                        app.rootFolder      = executationFolder;
                    else
                        app.executationMode = 'webApp';
                    end
                else
                    app.executationMode = 'built-in';
                end
                
                % WARNING MESSAGES
                warning('off', 'MATLAB:ui:javaframe:PropertyToBeRemoved')
                warning('off', 'MATLAB:subscripting:noSubscriptsSpecified')
                warning('off', 'MATLAB:structOnObject')
                warning('off', 'MATLAB:class:DestructorError')
                warning('off', 'MATLAB:modes:mode:InvalidPropertySet')
                warning('off', 'MATLAB:table:RowsAddedExistingVars')
                warning('off', 'MATLAB:colon:operandsNotRealScalar')

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % TRECHO TEMPORÁRIO RELACIONADO À GUI. REMOVER NA COMPILAÇÃO! %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                app.search_mainGrid.ColumnWidth{1}  = 0;
                app.search_Tab1Grid.RowHeight(4:14) = repmat({0}, 1, 11);

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % TRECHO TEMPORÁRIO RELACIONADO À GUI. REMOVER NA COMPILAÇÃO! %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                % STARTUP
                layout.winPosition(app)
                jsBackDoor_Initialization(app)

                startupTimerCreation(app)
                customizationTimerCreation(app)

                % Salvando na propriedade "UserData" as opções de ícone e o 
                % índice da aba, simplificando os ajustes decorrentes de uma 
                % alteração...
                app.menu_Button1.UserData                 = struct('iconOptions', {{'Home_36x36W.png',   'Home_36x36Y.png'}},   'tabGroup', 1);
                app.menu_Button2.UserData                 = struct('iconOptions', {{'Zoom_36x36W.png',   'Zoom_36x36Y.png'}},   'tabGroup', 2);
                app.menu_Button3.UserData                 = struct('iconOptions', {{'Report_36x36W.png', 'Report_36x36Y.png'}}, 'tabGroup', 3);

                app.search_ToolbarAnnotation.UserData     = struct('iconOptions', {{'Edit_18x18G.png',   'Edit_18x18B.png'}});
                app.search_ToolbarWordCloud.UserData      = struct('iconOptions', {{'Cloud_32x32G.png',  'Cloud_32x32B.png'}});
                app.search_ToolbarListOfProducts.UserData = struct('iconOptions', {{'Box_32x32G.png',    'Box_32x32B.png'}});

            catch ME
                layout.modalWindow(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            % ATUALIZA TABELA LOCAL DE ANOTAÇÕES
            % PENDENTE DEFINIR FORMA DE ATUALIZAÇÃO
            class.Annotation.DataUpload('Offline', app.annotationTable, app.General.Annotation.filePath);

            % DELETE TIMER
            h = timerfindall;
            stop(h); delete(h); clear h

            delete(app)
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            
            hitObject = struct(event).HitObject;
            switch hitObject
                case app.search_entryPoint
                    switch app.search_entryPoint.Value
                        case 'O que você quer pesquisar?'
                            set(app.search_entryPoint, 'FontColor', [0,0,0], 'Value', '')

                        otherwise
                            if app.search_ModeTokenSuggestion.Value 
                                if numel(app.search_entryPoint.Value) >= app.search_nMinCharacters.Value
                                    app.search_Suggestions.Visible = 1;
                                end
                            end
                    end

                case app.search_Suggestions
                    if isempty(app.search_Suggestions.Value)
                        waitfor(app.search_Suggestions, 'Value')
                    end

                    jsBackDoor_keyDownListener(app, struct('HTMLEventData', 'app.search_Suggestions', 'HTMLEventName', 'Enter'))

                otherwise
                    set(app.search_Suggestions, Visible=0, Value={})
                    if isempty(app.search_entryPoint.Value)
                        set(app.search_entryPoint, 'FontColor', [.8,.8,.8], 'Value', 'O que você quer pesquisar?')
                    end                    
            end

        end

        % Value changed function: menu_Button1, menu_Button2, menu_Button3
        function menu_mainButtonPushed(app, event)
            
            clickedButton = event.Source;

            if clickedButton.Value                
                tabIndex      = clickedButton.UserData.tabGroup;
              % tabIndex      = str2double(clickedButton.Tag);

                nonClickedButtons = findobj(app.menu_Grid, 'Type', 'uistatebutton', '-not', 'Tag', clickedButton.Tag);
                arrayfun(@(x) set(x, 'Value', 0, 'Icon', x.UserData.iconOptions{1}), nonClickedButtons)
                set(clickedButton, 'Icon', clickedButton.UserData.iconOptions{2})
                
                app.TabGroup.SelectedTab = app.TabGroup.Children(tabIndex);
                drawnow

                % A customização somente pode tem efeito se os componentes
                % já tiverem sido renderizados no HTML. Por essa razão, inclui-se 
                % um drawnow após a mudança da aba.
                jsBackDoor_Customizations(app, tabIndex)

            else
                event.Source.Value = 1;
            end

        end

        % Image clicked function: menu_ControlPanelVisibility, menu_Help
        function menu_auxiliarButtonPushed(app, event)
            
            switch event.Source
                case app.menu_ControlPanelVisibility
                    if app.search_mainGrid.ColumnWidth{1}
                        app.menu_ControlPanelVisibility.ImageSource = 'ArrowRight_32x32W.png';
                        app.search_mainGrid.ColumnWidth{1} = 0;
                        app.report_mainGrid.ColumnWidth{1} = 0;

                    else
                        app.menu_ControlPanelVisibility.ImageSource = 'ArrowLeft_32x32W.png';
                        app.search_mainGrid.ColumnWidth{1} = 330;
                        app.report_mainGrid.ColumnWidth{1} = 330;
                    end

                    focus(app.jsBackDoor)

                case app.menu_Help
                    try
                        [~, SCHLinks] = fcn.PublicLinks(app.rootFolder);
                        web(SCHLinks.Sharepoint)
                        
                    catch ME
                        layout.modalWindow(app.UIFigure, 'error', ME.message);
                    end
            end

        end

        % Image clicked function: search_menuBtn1Icon, 
        % ...and 2 other components
        function search_menuButtonPushed(app, event)
            
            idx = str2double(event.Source.Tag);
            NN  = numel(app.search_ControlMenu.ColumnWidth);

            for ii = 1:NN
                switch ii
                    case idx
                        eval(sprintf('app.search_menuBtn%dGrid.ColumnWidth{2} = "1x";', ii))
                    otherwise
                        eval(sprintf('app.search_menuBtn%dGrid.ColumnWidth{2} = 0;',    ii))
                end
            end

            columnWidth      = repmat({22}, 1, NN);
            columnWidth(idx) = {'1x'};

            app.search_ControlMenu.ColumnWidth     = columnWidth;
            app.search_menuUnderline.Layout.Column = idx;
            app.search_ControlTabGroup.SelectedTab = app.search_ControlTabGroup.Children(idx);

            focus(app.jsBackDoor)

        end

        % Value changing function: search_entryPoint
        function search_EntryPointValueChanging(app, event)

            if app.search_ModeTokenSuggestion.Value
                suggestion_EntryPointValueChanging(app, event.Value, true)

            else
                app.previousSearch = event.Value;
            end

        end

        % Image clicked function: search_entryPointImage
        function search_EntryPointImageClicked(app, event)
            
            listOfColumns = primaryFilter_ListOfColumns(app);

            if app.search_ModeTokenSuggestion.Value
                value2Search = fcn.PreProcessedData(app.search_entryPoint.Value);
                words2Search = app.search_Suggestions.Items;
                
                if ~isempty(words2Search)
                    nWords2Search  = numel(words2Search);
                    nWordsContains = sum(contains(words2Search, value2Search));
    
                    listOfWords    = words2Search(1:min([20, nWords2Search]));                    
    
                    if nWordsContains < nWords2Search
                        searchNote = ' e similares';
                    else
                        searchNote = '';
                    end
        
                    app.search_Metadata.Text = sprintf('Exibindo resultados para "<b>%s</b>"%s\n<p style="color: #808080; font-size:10px;">Palavras-chave: %s</p>', ...
                                                       app.search_entryPoint.Value, searchNote, jsonencode(struct('Qtd', sprintf('%d de %d', nWordsContains, nWords2Search), 'Amostra', {listOfWords}, 'Coluna', {listOfColumns})));
    
                    layout_TableVisibility(app)
                    primaryFilter_Filtering(app, words2Search, listOfColumns)
                end

            else
                words2Search = fcn.PreProcessedData(strsplit(app.search_entryPoint.Value, ','));

                if ~isempty(words2Search)
                    app.search_Metadata.Text = sprintf('Exibindo resultados para %s', strjoin("""<b>" + string(words2Search) + "</b>""", ', '));

                    primaryFilter_Filtering(app, words2Search, listOfColumns)
                end
            end

        end

        % Selection changed function: search_Table
        function search_TableSelectionChanged(app, event)
            
            [~, selectedHom, selectedHomUITableIndex, showedHom] = notes_SelectedRow(app);

            if ~isempty(selectedHom)
                if ~ismember(showedHom, selectedHom)
                    selected2showedHom       = selectedHom{1};

                    secundaryIndex           = app.search_Table.UserData.secundaryIndex;

                    selectedHomRawTableIndex = secundaryIndex(selectedHomUITableIndex);
                    relatedAnnotationTable   = notes_RelatedCacheTable(app, selected2showedHom);

                    layout_selectedHomInfo(app, selected2showedHom, selectedHomRawTableIndex, relatedAnnotationTable)
    
                    % Apresenta a nuvem de palavras apenas se visível...
                    if app.search_Tab1Grid.RowHeight{5}
                        if wordCloud_CheckCache(app, selected2showedHom, relatedAnnotationTable)
                            if exist('event', 'var')
                                dialogProgressFlag = true;
                            else
                                dialogProgressFlag = false;
                            end
    
                            wordCloud_PlotUpdate(app, selected2showedHom, false, dialogProgressFlag);
                        end
                    end
    
                    app.search_ToolbarAnnotation.Enable     = 1;
                    app.search_ToolbarWordCloud.Enable      = 1;
                    app.search_ToolbarListOfProducts.Enable = 1;    
                    app.search_WordCloudRefresh.Enable      = 1;                    
                end

            else
                layout_selectedHomInfo(app, '', [], [])

                if isa(app.wordCloudChart, 'matlab.graphics.chart.WordCloudChart') && ~isempty(app.search_WordCloudPanel.Tag)
                    app.wordCloudChart.SourceTable      = wordCloud_EmptyTable(app);
                    app.search_WordCloudPanel.Tag       = '';
                end

                app.search_ToolbarAnnotation.Enable     = 0;
                app.search_ToolbarWordCloud.Enable      = 0;
                app.search_ToolbarListOfProducts.Enable = 0;
                app.search_WordCloudRefresh.Enable      = 0;                
            end

            search_PanelVisibility(app)
            
        end

        % Selection changed function: filter_PrimaryPanel
        function search_ListOfPrimaryColumnsChanged(app, event)
            
            app.previousSearch = '';

            eventValue = app.search_entryPoint.Value;
            suggestion_EntryPointValueChanging(app, eventValue, false)
            
            search_EntryPointImageClicked(app)

        end

        % Value changed function: filter_ApplicantCheckBox, 
        % ...and 7 other components
        function search_secundaryFilterCheckBoxClicked(app, event)
            
            columnName   = event.Source.Text;
            editfieldObj = findobj(app.filter_SecundaryGrid, 'Tag', columnName);

            switch editfieldObj.Type
                case 'uieditfield'
                    if event.Source.Value
                        set(editfieldObj, 'Enable', 1, 'Value', '')
    
                    else
                        set(editfieldObj, 'Enable', 0, 'Value', '')
                        search_secundaryFiltering(app)
                    end

                case 'uidropdown'
                    if event.Source.Value
                        primaryIndex = app.search_Table.UserData.primaryIndex;
                        set(editfieldObj, 'Enable', 1, 'Items', [{''}; cellstr(unique(app.rawDataTable{primaryIndex, columnName}))])
    
                    else
                        set(editfieldObj, 'Enable', 0, 'Items', {})
                        search_secundaryFiltering(app)
                    end
            end

        end

        % Callback function: filter_Applicant, filter_CNPJ, 
        % ...and 6 other components
        function search_secundaryFiltering(app, event)
            
            primaryIndex = app.search_Table.UserData.primaryIndex;

            checkboxList = findobj(app.filter_SecundaryGrid, 'Type', 'uicheckbox', 'Value', 1);
            GUIColumns   = search_ColumnNames(app);

            if ~isempty(checkboxList)
                columnNames   = arrayfun(@(x) x.Text, checkboxList, 'UniformOutput', false);
                logicalMatrix = zeros(numel(primaryIndex), numel(columnNames), 'logical');
    
                for ii = 1:numel(columnNames)
                    columnName      = columnNames{ii};
                    cacheColumnName = suggestion_FindCache_ColumnName(app, columnName);
                    
                    if ~strcmp(columnName, cacheColumnName)
                        if exist('event', 'var') && strcmp(event.Source.Tag, columnName)
                            value2Search = fcn.PreProcessedData(event.Value);
                        else
                            editfieldObj = findobj(app.filter_SecundaryGrid, 'Tag', columnName);
                            value2Search = fcn.PreProcessedData(editfieldObj.Value);                        
                        end
        
                        logicalMatrix(:,ii) = contains(app.rawDataTable{primaryIndex, cacheColumnName}, value2Search, 'IgnoreCase', true);

                    else
                        if exist('event', 'var') && strcmp(event.Source.Tag, columnName)
                            value2Search = event.Value;
                        else
                            editfieldObj = findobj(app.filter_SecundaryGrid, 'Tag', columnName);
                            value2Search = editfieldObj.Value;                        
                        end

                        if isempty(value2Search)
                            logicalMatrix(:,ii) = true;
                        else
                            if iscategorical(app.rawDataTable{primaryIndex, cacheColumnName})
                                logicalMatrix(:,ii) = strcmpi(cellstr(app.rawDataTable{primaryIndex, cacheColumnName}), value2Search);
                            else
                                logicalMatrix(:,ii) = contains(app.rawDataTable{primaryIndex, cacheColumnName}, value2Search, 'IgnoreCase', true);
                            end
                        end
                    end
                end
                logicalArray   = all(logicalMatrix, 2);
                secundaryIndex = primaryIndex(logicalArray);
                    
                app.search_Table.Data = app.rawDataTable(secundaryIndex, GUIColumns);

            else
                secundaryIndex = primaryIndex;
                app.search_Table.Data = app.rawDataTable(primaryIndex, GUIColumns);
            end
            app.search_Table.UserData.secundaryIndex = secundaryIndex;

            % O foco muda para a tabela, caso tenha sido selecionado uma
            % lista suspensa. Em sendo uma caixa de texto, o foco permanece
            % na caixa de texto.
            focusFlag = false;
            if ~exist('event', 'var') || strcmp(event.Source.Type, 'uidropdown')
                focusFlag = true;
            end

            % Renderiza em tela o número de linhas, além de selecionar a primeira 
            % linha da tabela, caso a pesquisa retorne algo.
            layout_TableNumberOfRows(app)
            layout_TableInitialSelection(app, focusFlag)

            % Aplica estilo na tabela...
            layout_TableAddStyle(app)

            drawnow

        end

        % Image clicked function: search_ToolbarAnnotation, 
        % ...and 2 other components
        function search_ToolbarControlPanelVisibility(app, event)
            
            switch event.Source
                case app.search_ToolbarAnnotation
                    if app.search_Tab1Grid.RowHeight{6}
                        app.search_ToolbarAnnotation.ImageSource = app.search_ToolbarAnnotation.UserData.iconOptions{1};
                        app.search_Tab1Grid.RowHeight(6:7)       = {0,0};
                        
                        if ~app.search_Tab1Grid.RowHeight{8}
                            app.search_Tab1Grid.RowHeight(4:5)   = {0,0};
                        end

                    else
                        app.search_ToolbarAnnotation.ImageSource = app.search_ToolbarAnnotation.UserData.iconOptions{2};
                        app.search_Tab1Grid.RowHeight(4:7)       = {5,22,5,160};
                    end
                    search_PanelVisibility(app)

                case app.search_ToolbarWordCloud
                    if app.search_Tab1Grid.RowHeight{8}
                        app.search_ToolbarWordCloud.ImageSource   = app.search_ToolbarWordCloud.UserData.iconOptions{1};
                        app.search_Tab1Grid.RowHeight(8:10)       = {0,0,0};

                        if ~app.search_Tab1Grid.RowHeight{6}
                            app.search_Tab1Grid.RowHeight(4:5)    = {0,0};
                        end

                    else
                        app.search_ToolbarWordCloud.ImageSource   = app.search_ToolbarWordCloud.UserData.iconOptions{2};
                        app.search_Tab1Grid.RowHeight([4:5,8:10]) = {5,22,5,133,22};

                        showedHom              = app.search_ProductInfo.Tag;
                        relatedAnnotationTable = notes_RelatedCacheTable(app, showedHom);

                        if wordCloud_CheckCache(app, showedHom, relatedAnnotationTable)
                            wordCloud_PlotUpdate(app, showedHom, false, true);
                        end
                    end

                case app.search_ToolbarListOfProducts
                    if app.search_Tab1Grid.RowHeight{11}
                        app.search_ToolbarListOfProducts.ImageSource = app.search_ToolbarListOfProducts.UserData.iconOptions{1};
                        app.search_Tab1Grid.RowHeight(11:14)         = {0,0,0,0};

                    else
                        app.search_ToolbarListOfProducts.ImageSource = app.search_ToolbarListOfProducts.UserData.iconOptions{2};
                        app.search_Tab1Grid.RowHeight(11:14)         = {5,22,5,160};
                    end
            end

            focus(app.search_Table)

        end

        % Image clicked function: search_AnnotationPanelAdd
        function search_AddAnnotation(app, event)
            
            % O valor de um objeto "uitextarea" será sempre um cellstr.
            % O arranjo a seguir elimina linhas vazias, concatenando as
            % outras.
            app.search_AnnotationValue.Value = strtrim(app.search_AnnotationValue.Value);
            app.search_AnnotationValue.Value(cellfun(@(x) isempty(x), app.search_AnnotationValue.Value)) = [];
            app.search_AnnotationValue.Value = strjoin(app.search_AnnotationValue.Value, ' ');

            showedHom      = app.search_ProductInfo.Tag;
            
            attributeName  = app.search_AnnotationAttribute.Value;
            attributeValue = char(app.search_AnnotationValue.Value);

            if ~isempty(attributeValue)
                notes_Add2Cache(app, showedHom, attributeName, attributeValue, false)

            else
                layout.modalWindow(app.UIFigure, 'warning', 'Deve ser informado o valor do atributo.');
            end

        end

        % Image clicked function: search_WordCloudRefresh
        function search_ToolbarWordCloudRefresh(app, event)
            
            % Primeiro tenta atualizar a nuvem de palavras...
            showedHom = app.search_ProductInfo.Tag;
            wordCloud_PlotUpdate(app, showedHom, true, true);

            focus(app.search_Table)

        end

        % Value changed function: notes_WordCloudNumber
        function search_NumberOfWordsValueChanged(app, event)
            
            if ~isempty(app.wordCloudChart)
                app.wordCloudChart.MaxDisplayWords = str2double(app.notes_WordCloudNumber.Value);
            end

        end

        % Image clicked function: search_ListOfProductsAdd
        function search_ListOfProductsAddImageClicked(app, event)
            
            [~, selectedHom, ~, showedHom] = notes_SelectedRow(app);
            
            if isempty(selectedHom)
                msgWarning = 'Selecione ao menos um registro na tabela.';
                layout.modalWindow(app.UIFigure, "warning", msgWarning)
                return

            else
                if ~isequal(selectedHom, {showedHom})
                    msgQuestion = sprintf(['<font style="font-size:12px;">Homologação apresentada no painel à esquerda:\n• %s\n\n' ...
                                           'Homologações selecionadas em tabela:\n%s\n\n'        ...
                                           'Quais os registros devem ser adicionados à lista de produtos sob análise?</font>'], showedHom, strjoin("• " + string(selectedHom), '\n'));
                    
                    selection   = uiconfirm(app.UIFigure, msgQuestion, '', 'Interpreter', 'html',                                                      ...
                                                                           'Options', {'Apresentado em painel', 'Selecionados em tabela', 'Cancelar'}, ...
                                                                           'DefaultOption', 1, 'CancelOption', 3, 'Icon', 'question');
        
                    switch selection
                        case 'Apresentado em painel'
                            listOfHom2Add = {showedHom};

                        case 'Selecionados em tabela'
                            listOfHom2Add = selectedHom;

                        case 'Cancelar'
                            return
                    end

                else
                    listOfHom2Add = selectedHom;
                end

                if any(~ismember(listOfHom2Add, app.search_ListOfProducts.Items))
                    app.search_ListOfProducts.Items = unique([app.search_ListOfProducts.Items, listOfHom2Add'], 'stable');
                end
            end

            focus(app.search_Table)

        end

        % Menu selected function: ContextMenu_DeleteFcn
        function ContextMenu_DeleteFcnSelected(app, event)
            
            selectedProduct = app.search_ListOfProducts.Value;
            if ~isempty(selectedProduct)
                app.search_ListOfProducts.Items = setdiff(app.search_ListOfProducts.Items, selectedProduct);
            end

        end

        % Callback function: search_SelectedTableColumns
        function search_TableColumnsCheckedNodesChanged(app, event)
            
            checkedNodes   = app.search_SelectedTableColumns.CheckedNodes;
            checkedColumns = arrayfun(@(x) x.Text, checkedNodes, 'UniformOutput', false);

            staticColumns  = class.Constants.staticColumns;
            staticColumns(ismember(staticColumns, checkedColumns)) = [];
            
            if ~isempty(staticColumns)
                for ii = 1:numel(staticColumns)
                    staticColumnName = staticColumns{ii};
                    staticTreeNodes  = findobj(app.search_SelectedTableColumns, 'Text', staticColumnName);
                    
                    app.search_SelectedTableColumns.CheckedNodes(end+1) = staticTreeNodes;
                end
            end
            [columnNames, columnWidth] = search_ColumnNames(app);

            if (numel(columnNames) ~= width(app.search_Table.Data)) || any(~ismember(app.search_Table.ColumnName, upper(columnNames)))
                primaryIndex = app.search_Table.UserData.primaryIndex;    

                set(app.search_Table, 'Data', app.rawDataTable(primaryIndex, columnNames), ...
                                      'ColumnName', upper(columnNames), 'ColumnWidth', columnWidth)

                if strcmp(app.customizationTimer.Running, 'off')
                    start(app.customizationTimer)
                end
            end
            
        end

        % Selection changed function: search_ModePanel
        function search_ModePanelSelectionChanged(app, event)
            
            selectedButton = app.search_ModePanel.SelectedObject;
            switch selectedButton
                case app.search_ModeTokenSuggestion
                    app.search_Document.ColumnWidth = {400, '1x'};

                case app.search_ModeListOfWords
                    app.search_Document.ColumnWidth = {'1x', 0};

                    app.previousSearch   = '';
                    app.previousItemData = 0;
                    
                    set(app.search_Suggestions, Visible=0, Items={}, ItemsData=[])
            end

            set(app.search_entryPoint, 'FontColor', [.8,.8,.8], 'Value', 'O que você quer pesquisar?')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCH';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'Icon_36x36.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab1_Home = uitab(app.TabGroup);
            app.Tab1_Home.Title = 'HOME';
            app.home_splashscreenGrid = uigridlayout(app.Tab1_Home);
            app.home_splashscreenTitle = uilabel(app.home_splashscreenGrid);
            app.home_splashscreenTitle.Interpreter = 'html';
            app.home_splashscreenTitle.Text = 'SCH<font style="font-size:12px;"> v. 0.01</font>';
            app.home_splashscreenGIF = uiimage(app.home_splashscreenGrid);
            app.home_splashscreenGIF.Enable = 'off';
            app.home_splashscreenGIF.ImageSource = fullfile(pathToMLAPP, 'Icons', 'SplashScreen.gif');
            app.Tab2_Search = uitab(app.TabGroup);
            app.Tab2_Search.Title = 'SEARCH';
            app.search_mainGrid = uigridlayout(app.Tab2_Search);
            app.search_Document = uigridlayout(app.search_mainGrid);
            app.search_nRows = uilabel(app.search_Document);
            app.search_nRows.Visible = 'off';
            app.search_nRows.Text = '# 0 ';
            app.search_Table = uitable(app.search_Document);
            app.search_Table.ColumnName = {'HOMOLOGAÇÃO'; 'TIPO'; 'SOLICITANTE'; 'CNPJ/CPF'; 'FABRICANTE'; 'MODELO'; 'NOME COMERCIAL'; 'SITUAÇÃO'};
            app.search_Table.RowName = {};
            app.search_Table.SelectionType = 'row';
            app.search_Table.SelectionChangedFcn = createCallbackFcn(app, @search_TableSelectionChanged, true);
            app.search_Table.Visible = 'off';
            app.search_entryPointPanel = uipanel(app.search_Document);
            app.search_entryPointGrid = uigridlayout(app.search_entryPointPanel);
            app.search_entryPoint = uieditfield(app.search_entryPointGrid, 'text');
            app.search_entryPoint.CharacterLimits = [0 128];
            app.search_entryPoint.ValueChangingFcn = createCallbackFcn(app, @search_EntryPointValueChanging, true);
            app.search_entryPoint.Tag = 'PROMPT';
            app.search_entryPoint.Enable = 'off';
            app.search_entryPoint.Value = 'O que você quer pesquisar?';
            app.search_entryPointImage = uiimage(app.search_entryPointGrid);
            app.search_entryPointImage.ImageClickedFcn = createCallbackFcn(app, @search_EntryPointImageClicked, true);
            app.search_entryPointImage.Enable = 'off';
            app.search_entryPointImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Zoom_36x36.png');
            app.search_Metadata = uilabel(app.search_Document);
            app.search_Metadata.Visible = 'off';
            app.search_Metadata.Interpreter = 'html';
            app.search_Metadata.Text = {'Exibindo resultados para "<b>apple iphone</b>"'; '<p style="color: #808080; font-size:10px;">Pesquisa realizada nas colunas "Nome do Produto" e "Modelo Comercial".</p><p style="color: #808080; font-size:10px;">Pesquisa realizada nas colunas "Nome do Produto" e "Modelo Comercial".</p>'};
            app.search_Suggestions = uilistbox(app.search_Document);
            app.search_Suggestions.Items = {''};
            app.search_Suggestions.Tag = 'CAIXA DE BUSCA';
            app.search_Suggestions.Visible = 'off';
            app.search_Suggestions.Value = {};
            app.search_Control = uigridlayout(app.search_mainGrid);
            app.search_ControlTabGroup = uitabgroup(app.search_Control);
            app.search_Tab1 = uitab(app.search_ControlTabGroup);
            app.search_Tab1Grid = uigridlayout(app.search_Tab1);
            app.search_ProductInfoLabel = uilabel(app.search_Tab1Grid);
            app.search_ProductInfoLabel.Text = 'PRODUTO SELECIONADO';
            app.search_ToolbarAnnotation = uiimage(app.search_Tab1Grid);
            app.search_ToolbarAnnotation.ImageClickedFcn = createCallbackFcn(app, @search_ToolbarControlPanelVisibility, true);
            app.search_ToolbarAnnotation.Enable = 'off';
            app.search_ToolbarAnnotation.Tooltip = {'Anotação textual'};
            app.search_ToolbarAnnotation.ImageSource = 'Edit_18x18G.png';
            app.search_ToolbarWordCloud = uiimage(app.search_Tab1Grid);
            app.search_ToolbarWordCloud.ImageClickedFcn = createCallbackFcn(app, @search_ToolbarControlPanelVisibility, true);
            app.search_ToolbarWordCloud.Enable = 'off';
            app.search_ToolbarWordCloud.Tooltip = {'Nuvem de palavras'; '(Google)'};
            app.search_ToolbarWordCloud.ImageSource = 'Cloud_32x32G.png';
            app.search_ToolbarListOfProducts = uiimage(app.search_Tab1Grid);
            app.search_ToolbarListOfProducts.ImageClickedFcn = createCallbackFcn(app, @search_ToolbarControlPanelVisibility, true);
            app.search_ToolbarListOfProducts.Enable = 'off';
            app.search_ToolbarListOfProducts.Tooltip = {'Lista de produtos sob anáilse'};
            app.search_ToolbarListOfProducts.ImageSource = 'Box_32x32G.png';
            app.search_ProductInfoPanel = uipanel(app.search_Tab1Grid);
            app.search_ProductInfoGrid = uigridlayout(app.search_ProductInfoPanel);
            app.search_ProductInfo = uihtml(app.search_ProductInfoGrid);
            app.search_ProductInfo.HTMLSource = 'Warning.html';
            app.search_AnnotationPanelLabel = uilabel(app.search_Tab1Grid);
            app.search_AnnotationPanelLabel.Text = 'ANOTAÇÃO';
            app.search_AnnotationPanelAdd = uiimage(app.search_Tab1Grid);
            app.search_AnnotationPanelAdd.ImageClickedFcn = createCallbackFcn(app, @search_AddAnnotation, true);
            app.search_AnnotationPanelAdd.Visible = 'off';
            app.search_AnnotationPanelAdd.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_Add5.png');
            app.search_AnnotationPanel = uipanel(app.search_Tab1Grid);
            app.search_AnnotationGrid = uigridlayout(app.search_AnnotationPanel);
            app.search_AnnotationAttributeLabel = uilabel(app.search_AnnotationGrid);
            app.search_AnnotationAttributeLabel.Text = 'Atributo:';
            app.search_AnnotationAttribute = uidropdown(app.search_AnnotationGrid);
            app.search_AnnotationAttribute.Items = {'Fornecedor', 'Fabricante', 'Modelo', 'EAN', 'Outras informações'};
            app.search_AnnotationAttribute.Value = 'Fornecedor';
            app.search_AnnotationValueLabel = uilabel(app.search_AnnotationGrid);
            app.search_AnnotationValueLabel.Text = 'Valor:';
            app.search_AnnotationValue = uitextarea(app.search_AnnotationGrid);
            app.search_WordCloudPanel = uipanel(app.search_Tab1Grid);
            app.search_WordCloudRefreshGrid = uigridlayout(app.search_Tab1Grid);
            app.search_WordCloudRefresh = uiimage(app.search_WordCloudRefreshGrid);
            app.search_WordCloudRefresh.ImageClickedFcn = createCallbackFcn(app, @search_ToolbarWordCloudRefresh, true);
            app.search_WordCloudRefresh.Enable = 'off';
            app.search_WordCloudRefresh.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18x18G.png');
            app.search_ListOfProductsLabel = uilabel(app.search_Tab1Grid);
            app.search_ListOfProductsLabel.Text = 'LISTA DE PRODUTOS SOB ANÁLISE';
            app.search_ListOfProductsAdd = uiimage(app.search_Tab1Grid);
            app.search_ListOfProductsAdd.ImageClickedFcn = createCallbackFcn(app, @search_ListOfProductsAddImageClicked, true);
            app.search_ListOfProductsAdd.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_Add5.png');
            app.search_ListOfProducts = uilistbox(app.search_Tab1Grid);
            app.search_ListOfProducts.Items = {};
            app.search_ListOfProducts.Multiselect = 'on';
            app.search_ListOfProducts.Value = {};
            app.search_Tab2 = uitab(app.search_ControlTabGroup);
            app.Tab2Grid = uigridlayout(app.search_Tab2);
            app.filter_PrimaryPanelLabel = uilabel(app.Tab2Grid);
            app.filter_PrimaryPanelLabel.Text = 'PRIMÁRIA';
            app.filter_PrimaryPanel = uibuttongroup(app.Tab2Grid);
            app.filter_PrimaryPanel.SelectionChangedFcn = createCallbackFcn(app, @search_ListOfPrimaryColumnsChanged, true);
            app.filter_PrimaryOption1 = uiradiobutton(app.filter_PrimaryPanel);
            app.filter_PrimaryOption1.Text = 'Homologação';
            app.filter_PrimaryOption2 = uiradiobutton(app.filter_PrimaryPanel);
            app.filter_PrimaryOption2.Text = 'Solicitante | Fabricante';
            app.filter_PrimaryOption3 = uiradiobutton(app.filter_PrimaryPanel);
            app.filter_PrimaryOption3.Text = 'Modelo | Nome Comercial';
            app.filter_PrimaryOption3.Value = true;
            app.filter_SecundaryPanelLabel = uilabel(app.Tab2Grid);
            app.filter_SecundaryPanelLabel.Text = 'SECUNDÁRIA';
            app.filter_SecundaryPanel = uipanel(app.Tab2Grid);
            app.filter_SecundaryGrid = uigridlayout(app.filter_SecundaryPanel);
            app.filter_CertificationCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_CertificationCheckBox.ValueChangedFcn = createCallbackFcn(app, @search_secundaryFilterCheckBoxClicked, true);
            app.filter_CertificationCheckBox.Enable = 'off';
            app.filter_CertificationCheckBox.Text = 'Homologação';
            app.filter_Certification = uieditfield(app.filter_SecundaryGrid, 'text');
            app.filter_Certification.ValueChangingFcn = createCallbackFcn(app, @search_secundaryFiltering, true);
            app.filter_Certification.Tag = 'Homologação';
            app.filter_Certification.Enable = 'off';
            app.filter_ProductListCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_ProductListCheckBox.ValueChangedFcn = createCallbackFcn(app, @search_secundaryFilterCheckBoxClicked, true);
            app.filter_ProductListCheckBox.Enable = 'off';
            app.filter_ProductListCheckBox.Text = 'Tipo';
            app.filter_ProductList = uidropdown(app.filter_SecundaryGrid);
            app.filter_ProductList.Items = {''};
            app.filter_ProductList.ValueChangedFcn = createCallbackFcn(app, @search_secundaryFiltering, true);
            app.filter_ProductList.Tag = 'Tipo';
            app.filter_ProductList.Enable = 'off';
            app.filter_ProductList.Value = '';
            app.filter_ApplicantCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_ApplicantCheckBox.ValueChangedFcn = createCallbackFcn(app, @search_secundaryFilterCheckBoxClicked, true);
            app.filter_ApplicantCheckBox.Enable = 'off';
            app.filter_ApplicantCheckBox.Text = 'Solicitante';
            app.filter_Applicant = uieditfield(app.filter_SecundaryGrid, 'text');
            app.filter_Applicant.ValueChangingFcn = createCallbackFcn(app, @search_secundaryFiltering, true);
            app.filter_Applicant.Tag = 'Solicitante';
            app.filter_Applicant.Enable = 'off';
            app.filter_CNPJCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_CNPJCheckBox.ValueChangedFcn = createCallbackFcn(app, @search_secundaryFilterCheckBoxClicked, true);
            app.filter_CNPJCheckBox.Enable = 'off';
            app.filter_CNPJCheckBox.Text = 'CNPJ/CPF';
            app.filter_CNPJ = uieditfield(app.filter_SecundaryGrid, 'text');
            app.filter_CNPJ.ValueChangingFcn = createCallbackFcn(app, @search_secundaryFiltering, true);
            app.filter_CNPJ.Tag = 'CNPJ/CPF';
            app.filter_CNPJ.Enable = 'off';
            app.filter_ManufacturerCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_ManufacturerCheckBox.ValueChangedFcn = createCallbackFcn(app, @search_secundaryFilterCheckBoxClicked, true);
            app.filter_ManufacturerCheckBox.Enable = 'off';
            app.filter_ManufacturerCheckBox.Text = 'Fabricante';
            app.filter_Manufacturer = uieditfield(app.filter_SecundaryGrid, 'text');
            app.filter_Manufacturer.ValueChangingFcn = createCallbackFcn(app, @search_secundaryFiltering, true);
            app.filter_Manufacturer.Tag = 'Fabricante';
            app.filter_Manufacturer.Enable = 'off';
            app.filter_ModelCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_ModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @search_secundaryFilterCheckBoxClicked, true);
            app.filter_ModelCheckBox.Enable = 'off';
            app.filter_ModelCheckBox.Text = 'Modelo';
            app.filter_Model = uieditfield(app.filter_SecundaryGrid, 'text');
            app.filter_Model.ValueChangingFcn = createCallbackFcn(app, @search_secundaryFiltering, true);
            app.filter_Model.Tag = 'Modelo';
            app.filter_Model.Enable = 'off';
            app.filter_TradeNameCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_TradeNameCheckBox.ValueChangedFcn = createCallbackFcn(app, @search_secundaryFilterCheckBoxClicked, true);
            app.filter_TradeNameCheckBox.Enable = 'off';
            app.filter_TradeNameCheckBox.Text = 'Nome Comercial';
            app.filter_TradeName = uieditfield(app.filter_SecundaryGrid, 'text');
            app.filter_TradeName.ValueChangingFcn = createCallbackFcn(app, @search_secundaryFiltering, true);
            app.filter_TradeName.Tag = 'Nome Comercial';
            app.filter_TradeName.Enable = 'off';
            app.filter_StatusCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_StatusCheckBox.ValueChangedFcn = createCallbackFcn(app, @search_secundaryFilterCheckBoxClicked, true);
            app.filter_StatusCheckBox.Enable = 'off';
            app.filter_StatusCheckBox.Text = 'Situação';
            app.filter_Status = uidropdown(app.filter_SecundaryGrid);
            app.filter_Status.Items = {''};
            app.filter_Status.ValueChangedFcn = createCallbackFcn(app, @search_secundaryFiltering, true);
            app.filter_Status.Tag = 'Situação';
            app.filter_Status.Enable = 'off';
            app.filter_Status.Value = '';
            app.config_htmlListView_2 = uicheckbox(app.Tab2Grid);
            app.config_htmlListView_2.Enable = 'off';
            app.config_htmlListView_2.Text = 'Considerar anotações na busca primária textual.';
            app.search_Tab3 = uitab(app.search_ControlTabGroup);
            app.Tab3Grid = uigridlayout(app.search_Tab3);
            app.config_Label_2 = uilabel(app.Tab3Grid);
            app.config_Label_2.Text = 'VISIBILIDADE DE COLUNAS';
            app.search_SelectedTableColumns = uitree(app.Tab3Grid, 'checkbox');
            % Assign Checked Nodes
            app.search_SelectedTableColumns.CheckedNodesChangedFcn = createCallbackFcn(app, @search_TableColumnsCheckedNodesChanged, true);
            app.search_ModeLabel = uilabel(app.Tab3Grid);
            app.search_ModeLabel.Text = 'MODO';
            app.search_ModePanel = uibuttongroup(app.Tab3Grid);
            app.search_ModePanel.SelectionChangedFcn = createCallbackFcn(app, @search_ModePanelSelectionChanged, true);
            app.search_ModeTokenSuggestion = uiradiobutton(app.search_ModePanel);
            app.search_ModeTokenSuggestion.Text = '<p style="text-align:justify; line-height:1.1;">Pesquisa orientada à palavra que está sendo escrita, sugerindo <i>tokens</i> relacionados.</p>';
            app.search_ModeTokenSuggestion.Interpreter = 'html';
            app.search_ModeTokenSuggestion.Value = true;
            app.search_ModeListOfWords = uiradiobutton(app.search_ModePanel);
            app.search_ModeListOfWords.Text = '<p style="text-align:justify; line-height:1.1;">Pesquisa orientada à uma lista de palavras separadas por vírgulas. Não há sugestão de <i>tokens</i> relacionados.</p>';
            app.search_ModeListOfWords.Interpreter = 'html';
            app.config_Label_3 = uilabel(app.Tab3Grid);
            app.config_Label_3.Text = 'ALGORITMO DE SUGESTÃO DE TOKENS RELACIONADOS';
            app.config_SuggestionPanel = uipanel(app.Tab3Grid);
            app.config_SuggestionGrid = uigridlayout(app.config_SuggestionPanel);
            app.search_nMinCharactersLabel = uilabel(app.config_SuggestionGrid);
            app.search_nMinCharactersLabel.Text = {'Qtd. mínima'; 'caracteres:'};
            app.search_nMinCharacters = uispinner(app.config_SuggestionGrid);
            app.search_nMinCharacters.Limits = [2 4];
            app.search_nMinCharacters.RoundFractionalValues = 'on';
            app.search_nMinCharacters.ValueDisplayFormat = '%d';
            app.search_nMinCharacters.Value = 2;
            app.search_nMinWordsLabel = uilabel(app.config_SuggestionGrid);
            app.search_nMinWordsLabel.Interpreter = 'html';
            app.search_nMinWordsLabel.Text = {'Qtd. mínima '; '<i>tokens</i>:'};
            app.search_nMinWords = uidropdown(app.config_SuggestionGrid);
            app.search_nMinWords.Items = {'20', '50', '100'};
            app.search_nMinWords.Value = '20';
            app.ANOTAODOTIPOWORDCLOUDLabel = uilabel(app.Tab3Grid);
            app.ANOTAODOTIPOWORDCLOUDLabel.Text = 'ANOTAÇÃO DO TIPO "WORDCLOUD"';
            app.notes_WordCloudPanel = uipanel(app.Tab3Grid);
            app.notes_WordCloudGrid = uigridlayout(app.notes_WordCloudPanel);
            app.notes_WordCloudNumberLabel = uilabel(app.notes_WordCloudGrid);
            app.notes_WordCloudNumberLabel.Text = {'Qtd. máxima'; 'palavras:'};
            app.notes_WordCloudNumber = uidropdown(app.notes_WordCloudGrid);
            app.notes_WordCloudNumber.Items = {'10', '25', '50'};
            app.notes_WordCloudNumber.ValueChangedFcn = createCallbackFcn(app, @search_NumberOfWordsValueChanged, true);
            app.notes_WordCloudNumber.Value = '25';
            app.notes_WordCloudColumnLabel = uilabel(app.notes_WordCloudGrid);
            app.notes_WordCloudColumnLabel.Text = {'Busca de palavras '; 'orientada à coluna:'};
            app.notes_WordCloudColumn = uidropdown(app.notes_WordCloudGrid);
            app.notes_WordCloudColumn.Items = {'Modelo', 'Nome Comercial'};
            app.notes_WordCloudColumn.Value = 'Modelo';
            app.search_ControlMenu = uigridlayout(app.search_Control);
            app.search_menuUnderline = uiimage(app.search_ControlMenu);
            app.search_menuUnderline.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LineH.png');
            app.search_menuBtn1Grid = uigridlayout(app.search_ControlMenu);
            app.search_menuBtn1Label = uilabel(app.search_menuBtn1Grid);
            app.search_menuBtn1Label.Text = 'Aspectos gerais';
            app.search_menuBtn1Icon = uiimage(app.search_menuBtn1Grid);
            app.search_menuBtn1Icon.ImageClickedFcn = createCallbackFcn(app, @search_menuButtonPushed, true);
            app.search_menuBtn1Icon.Tag = '1';
            app.search_menuBtn1Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Connect_18x18.png');
            app.search_menuBtn2Grid = uigridlayout(app.search_ControlMenu);
            app.search_menuBtn2Label = uilabel(app.search_menuBtn2Grid);
            app.search_menuBtn2Label.Text = 'Filtragem';
            app.search_menuBtn2Icon = uiimage(app.search_menuBtn2Grid);
            app.search_menuBtn2Icon.ImageClickedFcn = createCallbackFcn(app, @search_menuButtonPushed, true);
            app.search_menuBtn2Icon.Tag = '2';
            app.search_menuBtn2Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Filter_18x18.png');
            app.search_menuBtn3Grid = uigridlayout(app.search_ControlMenu);
            app.search_menuBtn3Label = uilabel(app.search_menuBtn3Grid);
            app.search_menuBtn3Label.Text = 'Configurações';
            app.search_menuBtn3Icon = uiimage(app.search_menuBtn3Grid);
            app.search_menuBtn3Icon.ImageClickedFcn = createCallbackFcn(app, @search_menuButtonPushed, true);
            app.search_menuBtn3Icon.Tag = '3';
            app.search_menuBtn3Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Dots_18x18.png');
            app.Tab3_Report = uitab(app.TabGroup);
            app.Tab3_Report.Title = 'REPORT';
            app.report_mainGrid = uigridlayout(app.Tab3_Report);
            app.report_Document = uigridlayout(app.report_mainGrid);
            app.report_ListOfProductsLabel = uilabel(app.report_Document);
            app.report_ListOfProductsLabel.Visible = 'off';
            app.report_ListOfProductsLabel.Text = 'PRODUTOS INSPECIONADOS';
            app.report_ListOfProducts = uitable(app.report_Document);
            app.report_ListOfProducts.ColumnName = {'ID'; 'ATRIBUTOS'; 'QTD.'; 'VALOR UNITÁRIO|(R$)'};
            app.report_ListOfProducts.RowName = {};
            app.report_ListOfProducts.SelectionType = 'row';
            app.report_ListOfProducts.Multiselect = 'off';
            app.report_ListOfProducts.Visible = 'off';
            app.report_Control = uigridlayout(app.report_mainGrid);
            app.menu_TabGroup_2 = uitabgroup(app.report_Control);
            app.Tab1_2 = uitab(app.menu_TabGroup_2);
            app.Tab1Grid_2 = uigridlayout(app.Tab1_2);
            app.search_ListOfProducts_2 = uilistbox(app.Tab1Grid_2);
            app.search_ListOfProducts_2.Items = {};
            app.search_ListOfProducts_2.Value = {};
            app.search_ListOfProductsLabel_2 = uilabel(app.Tab1Grid_2);
            app.search_ListOfProductsLabel_2.Text = 'LISTA DE PRODUTOS SOB ANÁLISE';
            app.report_SelectedProductPanel = uipanel(app.Tab1Grid_2);
            app.report_SelectedProductGrid = uigridlayout(app.report_SelectedProductPanel);
            app.report_TypeLabel = uilabel(app.report_SelectedProductGrid);
            app.report_TypeLabel.Text = 'Tipo:';
            app.report_Type = uidropdown(app.report_SelectedProductGrid);
            app.report_Type.Items = {''};
            app.report_Type.Value = '';
            app.report_QuantityLabel = uilabel(app.report_SelectedProductGrid);
            app.report_QuantityLabel.Text = 'Quantidade:';
            app.report_Quantity = uieditfield(app.report_SelectedProductGrid, 'numeric');
            app.report_MonetaryValueLabel = uilabel(app.report_SelectedProductGrid);
            app.report_MonetaryValueLabel.Text = 'Valor unitário (R$):';
            app.report_MonetaryValue = uieditfield(app.report_SelectedProductGrid, 'numeric');
            app.report_ComplementaryNotesLabel = uilabel(app.report_SelectedProductGrid);
            app.report_ComplementaryNotesLabel.Text = 'Informações complementares:';
            app.report_ComplementaryNotes = uitextarea(app.report_SelectedProductGrid);
            app.report_TypeLabel_2 = uilabel(app.report_SelectedProductGrid);
            app.report_TypeLabel_2.Text = 'Situação:';
            app.report_Type_2 = uidropdown(app.report_SelectedProductGrid);
            app.report_Type_2.Items = {'', 'REGULAR', 'IRREGULAR'};
            app.report_Type_2.Value = '';
            app.report_addProduct = uiimage(app.report_SelectedProductGrid);
            app.report_addProduct.Enable = 'off';
            app.report_addProduct.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Add_18x18.png');
            app.ButtonGroup = uibuttongroup(app.report_SelectedProductGrid);
            app.PRODUTOHOMOLOGADOButton = uiradiobutton(app.ButtonGroup);
            app.PRODUTOHOMOLOGADOButton.Text = 'PRODUTO HOMOLOGADO';
            app.PRODUTOHOMOLOGADOButton.Interpreter = 'html';
            app.PRODUTOHOMOLOGADOButton.Value = true;
            app.PRODUTOfontstylecolorredNOfontHOMOLOGADOButton = uiradiobutton(app.ButtonGroup);
            app.PRODUTOfontstylecolorredNOfontHOMOLOGADOButton.Text = 'PRODUTO <font style="color:red;">NÃO</font> HOMOLOGADO';
            app.PRODUTOfontstylecolorredNOfontHOMOLOGADOButton.Interpreter = 'html';
            app.PRODUTOfontstylecolorredNOPASSVELfontDEHOMOLOGAOButton = uiradiobutton(app.ButtonGroup);
            app.PRODUTOfontstylecolorredNOPASSVELfontDEHOMOLOGAOButton.Text = 'PRODUTO <font style="color:red;">NÃO PASSÍVEL</font> DE HOMOLOGAÇÃO';
            app.PRODUTOfontstylecolorredNOPASSVELfontDEHOMOLOGAOButton.Interpreter = 'html';
            app.HomologaoEditFieldLabel = uilabel(app.report_SelectedProductGrid);
            app.HomologaoEditFieldLabel.Text = 'Homologação:';
            app.HomologaoEditField = uieditfield(app.report_SelectedProductGrid, 'text');
            app.FabricanteEditFieldLabel = uilabel(app.report_SelectedProductGrid);
            app.FabricanteEditFieldLabel.Text = 'Fabricante:';
            app.FabricanteEditField = uieditfield(app.report_SelectedProductGrid, 'text');
            app.ModeloEditFieldLabel = uilabel(app.report_SelectedProductGrid);
            app.ModeloEditFieldLabel.Text = 'Modelo:';
            app.ModeloEditField = uieditfield(app.report_SelectedProductGrid, 'text');
            app.Tab2_2 = uitab(app.menu_TabGroup_2);
            app.Tab3_2 = uitab(app.menu_TabGroup_2);
            app.menu_ControlGrid_2 = uigridlayout(app.report_Control);
            app.menu_Underline_2 = uiimage(app.menu_ControlGrid_2);
            app.menu_Underline_2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LineH.png');
            app.menu_Button1Grid_2 = uigridlayout(app.menu_ControlGrid_2);
            app.menu_Button1Label_2 = uilabel(app.menu_Button1Grid_2);
            app.menu_Button1Label_2.Text = 'Aspectos gerais';
            app.menu_Button1Icon_2 = uiimage(app.menu_Button1Grid_2);
            app.menu_Button1Icon_2.Tag = '1';
            app.menu_Button1Icon_2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Connect_18x18.png');
            app.menu_Button2Grid_2 = uigridlayout(app.menu_ControlGrid_2);
            app.menu_Button2Label_2 = uilabel(app.menu_Button2Grid_2);
            app.menu_Button2Label_2.Text = 'Filtragem';
            app.menu_Button2Icon_2 = uiimage(app.menu_Button2Grid_2);
            app.menu_Button2Icon_2.Tag = '2';
            app.menu_Button2Icon_2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Filter_18x18.png');
            app.menu_Button3Grid_2 = uigridlayout(app.menu_ControlGrid_2);
            app.menu_Button3Label_2 = uilabel(app.menu_Button3Grid_2);
            app.menu_Button3Label_2.Text = 'Configurações';
            app.menu_Button3Icon_2 = uiimage(app.menu_Button3Grid_2);
            app.menu_Button3Icon_2.Tag = '3';
            app.menu_Button3Icon_2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Dots_18x18.png');
            app.TabGroupMenu = uipanel(app.GridLayout);
            app.menu_Grid = uigridlayout(app.TabGroupMenu);
            app.menu_Grid.Tag = 'COLORLOCKED';
            app.menu_Button1 = uibutton(app.menu_Grid, 'state');
            app.menu_Button1.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button1.Tag = '1';
            app.menu_Button1.Tooltip = {''};
            app.menu_Button1.Icon = 'Home_36x36Y.png';
            app.menu_Button1.Text = '';
            app.menu_Button1.Value = true;
            app.menu_Separator1 = uiimage(app.menu_Grid);
            app.menu_Separator1.Enable = 'off';
            app.menu_Separator1.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LineV_W.png');
            app.menu_Button2 = uibutton(app.menu_Grid, 'state');
            app.menu_Button2.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button2.Tag = '2';
            app.menu_Button2.Tooltip = {''};
            app.menu_Button2.Icon = 'Zoom_36x36W.png';
            app.menu_Button2.Text = '';
            app.menu_Button3 = uibutton(app.menu_Grid, 'state');
            app.menu_Button3.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button3.Tag = '3';
            app.menu_Button3.Enable = 'off';
            app.menu_Button3.Tooltip = {''};
            app.menu_Button3.Icon = 'Report_36x36W.png';
            app.menu_Button3.Text = '';
            app.menu_userPathGrid = uigridlayout(app.menu_Grid);
            app.menu_userPath = uidropdown(app.menu_userPathGrid);
            app.menu_userPath.Items = {'Path'};
            app.menu_userPath.Value = 'Path';
            app.menu_searchPath = uiimage(app.menu_Grid);
            app.menu_searchPath.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_OpenFile.png');
            app.menu_ControlPanelVisibility = uiimage(app.menu_Grid);
            app.menu_ControlPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @menu_auxiliarButtonPushed, true);
            app.menu_ControlPanelVisibility.Enable = 'off';
            app.menu_ControlPanelVisibility.ImageSource = fullfile(pathToMLAPP, 'Icons', 'ArrowRight_32x32W.png');
            app.menu_Help = uiimage(app.menu_Grid);
            app.menu_Help.ImageClickedFcn = createCallbackFcn(app, @menu_auxiliarButtonPushed, true);
            app.menu_Help.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Info_36x36W.png');
            app.jsBackDoor = uihtml(app.menu_Grid);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu_DeleteFcn = uimenu(app.ContextMenu);
            app.ContextMenu_DeleteFcn.MenuSelectedFcn = createCallbackFcn(app, @ContextMenu_DeleteFcnSelected, true);
            app.ContextMenu_DeleteFcn.Text = 'Excluir';
            % Assign app.ContextMenu
            app.search_ListOfProducts.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSCH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end